classdef DataConversionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataConversionAppUIFigure  matlab.ui.Figure
        groupingPanel              matlab.ui.container.Panel
        fileNamesList              matlab.ui.control.ListBox
        dataLabel                  matlab.ui.control.Label
        loadButton                 matlab.ui.control.Button
        signalPanel                matlab.ui.container.Panel
        signalsList                matlab.ui.control.ListBox
        visualizeButton            matlab.ui.control.Button
        dataLossPanel              matlab.ui.container.Panel
        IntervalEditFieldLabel     matlab.ui.control.Label
        dataLossIntervalText       matlab.ui.control.NumericEditField
        checkDataLossButton        matlab.ui.control.Button
        dataLossText               matlab.ui.control.TextArea
        dataLossLabel              matlab.ui.control.Label
        exportPanel                matlab.ui.container.Panel
        saveDataMatlabButton       matlab.ui.control.Button
        saveDataText               matlab.ui.control.Button
        plotAxes                   matlab.ui.control.UIAxes
        supportedFormatsText       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
        %UI
        uiHandles;
        figureHandle;
        plotHandle;
        
        %data
        columnNames;
        data;
        
        %data loader
        dataLoader; % Description
    end
    
    methods (Access = private)
        
        function plotData(app)
            if isempty(app.data)
                if ~isempty(app.plotHandle)
                    delete(app.plotHandle);
                    app.plotHandle = [];
                    cla(app.plotHandle);
                end
            else
                selectedIdxs = app.getSelectedColumnIdxs();
                app.plotHandle = plot(app.plotAxes,app.data(:,selectedIdxs));
            end
        end
        
        function populateFileNamesList(app)
            files = [dir(fullfile(Constants.kDataPath, '*.mat'));...
                dir(fullfile(Constants.kDataPath, '*.txt'))];
            
            nFiles = length(files);
            filesStr = cell(1,nFiles);
            
            for i = 1 : length(files)
                filesStr{i} = files(i).name;
            end
            
            app.fileNamesList.Items = filesStr;
        end
        
        
        function handleSelectionChanged(app,~,~)
            app.updateFileName();
        end
        
        function loadData(app)
            fileName = app.fileNamesList.Value;
            if ~isempty(fileName)
                [app.data, app.columnNames] = app.dataLoader.loadData(fileName);
            end
        end
        
        %ui
        
        function selectedColumnIdxs = getSelectedColumnIdxs(app)
            selectedColumnIdxsStr = app.signalsList.Value;
            [~,selectedColumnIdxs] = ismember(selectedColumnIdxsStr,app.signalsList.Items);
        end
        
        function value = getDataLossInterval(app)
            value = app.dataLossIntervalText.Value;
        end
        
        function updateSignalsList(app)
            if ~isempty(app.columnNames)
                app.signalsList.Items = app.columnNames;
                app.signalsList.Value = app.columnNames{1};
            end
        end
        
        function updateDataLabel(app)
            
            nSamples = size(app.data,1);
            nSeconds = nSamples / 200;
            nMinutes = nSeconds / 60;
            fileName = app.fileNamesList.Value;
            if ~isempty(fileName)
                str = sprintf('%d samples (%d minutes)\n',nSamples,int32(nMinutes));
                app.dataLabel.Text = str;
            end
        end
    
        %{
        function cutData(app)
            startSample = app.getStartSample();
            endSample = app.getEndSample();
            numSamples = size(app.data,1);
            if startSample > 0 && startSample < endSample && endSample <= numSamples
                app.data = app.data(startSample:endSample,:);
                app.plotData();
                app.updateEndText();
            end
        
        end
        %}
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.dataLoader = DataLoader();
            
            app.populateFileNamesList();
            app.loadData();
            app.updateSignalsList();
            app.plotData();
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            if ~isempty(app.data)
                app.data = [];
                app.columnNames = [];
            end
            
            app.loadData();
            app.updateDataLabel();
            app.updateSignalsList();
            app.plotData();
        end

        % Button pushed function: visualizeButton
        function visualizeButtonPushed(app, event)
            app.plotData();
        end

        % Button pushed function: saveDataMatlabButton
        function saveDataMatlabButtonPushed(app, event)
            
            fileName = app.fileNamesList.Value;
            if ~isempty(fileName)
                dataTable = array2table(app.data);
                dataTable.Properties.VariableNames = app.columnNames;
                fileName = Helper.removeFileExtension(fileName);
                app.dataLoader.saveData(dataTable,fileName);
            end
        end

        % Button pushed function: saveDataText
        function saveDataTextButtonPushed(app, event)
            
            fileName = app.fileNamesList.Value;
            if ~isempty(fileName)
                fileName = Helper.removeFileExtension(fileName);
                app.dataLoader.saveTextData(app.data,app.columnNames,fileName);
            end
        end

        % Button pushed function: checkDataLossButton
        function checkDataLossButtonPushed(app, event)
            if ~isempty(app.data)
                selectedSignal = app.getSelectedColumnIdxs();
                if length(selectedSignal) == 1
                    
                    dataLossInterval = app.getDataLossInterval();
                    if ~isempty(dataLossInterval)
                        
                        signal = app.data(:,selectedSignal);
                        
                        validDataLossSignal = issorted(signal);
                        
                        if validDataLossSignal
                            firstSampleTime = signal(1);
                            lastSampleTime = signal(end);
                            expectedSamples = (lastSampleTime - firstSampleTime);
                            
                            nMissingPoints = Helper.countMissingPoints(unique(signal),dataLossInterval);
                            dataLossPercent = 100 * double(nMissingPoints) / double(expectedSamples);
                            app.dataLossLabel.Text = sprintf('data loss: %.1f%%',dataLossPercent);
                        else
                            app.dataLossLabel.Text = sprintf('invalid signal');
                        end
                    end
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataConversionAppUIFigure = uifigure;
            app.dataConversionAppUIFigure.Name = 'Data Conversion App';
            app.groupingPanel = uipanel(app.dataConversionAppUIFigure);
            app.groupingPanel.Title = 'Data';
            app.fileNamesList = uilistbox(app.groupingPanel);
            app.fileNamesList.Items = {};
            app.fileNamesList.Value = {};
            app.dataLabel = uilabel(app.groupingPanel);
            app.dataLabel.Text = '';
            app.loadButton = uibutton(app.groupingPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'Load';
            app.signalPanel = uipanel(app.dataConversionAppUIFigure);
            app.signalPanel.Title = 'Signal';
            app.signalsList = uilistbox(app.signalPanel);
            app.signalsList.Items = {};
            app.signalsList.Value = {};
            app.visualizeButton = uibutton(app.signalPanel, 'push');
            app.visualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualizeButton.Text = 'Visualize';
            app.dataLossPanel = uipanel(app.dataConversionAppUIFigure);
            app.dataLossPanel.Title = 'Data Loss';
            app.IntervalEditFieldLabel = uilabel(app.dataLossPanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.dataLossIntervalText = uieditfield(app.dataLossPanel, 'numeric');
            app.dataLossIntervalText.Value = 1;
            app.checkDataLossButton = uibutton(app.dataLossPanel, 'push');
            app.checkDataLossButton.ButtonPushedFcn = createCallbackFcn(app, @checkDataLossButtonPushed, true);
            app.checkDataLossButton.Text = 'Check Data Loss';
            app.dataLossText = uitextarea(app.dataLossPanel);
            app.dataLossText.Editable = 'off';
            app.dataLossText.Value = {'select the timestamp in the signal list'};
            app.dataLossLabel = uilabel(app.dataLossPanel);
            app.dataLossLabel.Text = '';
            app.exportPanel = uipanel(app.dataConversionAppUIFigure);
            app.exportPanel.Title = 'Export';
            app.saveDataMatlabButton = uibutton(app.exportPanel, 'push');
            app.saveDataMatlabButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataMatlabButtonPushed, true);
            app.saveDataMatlabButton.Text = 'Save Data Matlab';
            app.saveDataText = uibutton(app.exportPanel, 'push');
            app.saveDataText.ButtonPushedFcn = createCallbackFcn(app, @saveDataTextButtonPushed, true);
            app.saveDataText.Text = 'Save Data Text';
            app.plotAxes = uiaxes(app.dataConversionAppUIFigure);
            title(app.plotAxes, '')
            xlabel(app.plotAxes, 'X')
            ylabel(app.plotAxes, 'Y')
            app.supportedFormatsText = uitextarea(app.dataConversionAppUIFigure);
            app.supportedFormatsText.Editable = 'off';
            app.supportedFormatsText.Value = {'supported formats are: .mat and .txt'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataConversionApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataConversionAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataConversionAppUIFigure)
        end
    end
end