classdef SynchronizationDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                 matlab.ui.Figure
        addButton              matlab.ui.control.Button
        samplesAndFramesTable  matlab.ui.control.Table
        TextArea               matlab.ui.control.TextArea
        SaveButton             matlab.ui.control.Button
        removeButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        synchronizationFile; % contains a list of samples and synchronization points
        selectedRow;
    end
    
    properties (Access = public)
        delegate; % contains a list of samples and synchronization points
    end
    
    methods (Access = private)
        
        function updateSamplesAndFramesTable(app)
            app.samplesAndFramesTable.Data =...
                [double(cell2mat(app.synchronizationFile.synchronizationPointsMap.keys)'),...
                cell2mat(app.synchronizationFile.synchronizationPointsMap.values)'];
        end
    end
    
    methods (Access = public)
        
        function addSynchronizationPoint(app,sample,frame)
            if ~isempty(sample) && ~isempty(frame)
                app.synchronizationFile.setSynchronizationPoint(sample,frame);
                app.updateSamplesAndFramesTable();
            end
        end
        
        function setSynchronizationFile(app,synchronizationFile)
            app.synchronizationFile = synchronizationFile;
            app.updateSamplesAndFramesTable();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, delegate, synchronizationFile)
            
            app.delegate = delegate;
            
            if nargin > 2
                app.synchronizationFile = synchronizationFile;
                app.updateSamplesAndFramesTable();
            end
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            app.delegate.handleSynchronizationPointAdded();
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
             app.delegate.handleSynchronizationDialogClosed();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isempty(app.synchronizationFile)
                if isempty(app.synchronizationFile.fileName)
                    fileName = 'synchronization.txt';
                else
                    fileName = app.synchronizationFile.fileName;
                end
                DataLoader.SaveSynchronizationFile(app.synchronizationFile,fileName);
            end
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            if ~isempty(app.selectedRow)
                sample = app.samplesAndFramesTable.Data(app.selectedRow,1);
                app.synchronizationFile.removeSynchronizationPointWithSample(sample);
                app.samplesAndFramesTable.Data(app.selectedRow,:) = [];
                app.delegate.handleSynchronizationPointDeleted(app);
            end
        end

        % Cell selection callback: samplesAndFramesTable
        function samplesAndFramesTableCellSelection(app, event)
            app.selectedRow = event.Indices(1);
        end

        % Cell edit callback: samplesAndFramesTable
        function samplesAndFramesTableCellEdit(app, event)
            
            indices = event.Indices;
            
            if(indices(2) == 1)%sample changed
                app.synchronizationFile.removeSynchronizationPointWithSample(event.PreviousData);
                frame = app.samplesAndFramesTable.Data(indices(1),2);
                app.synchronizationFile.setSynchronizationPoint(event.NewData,frame);
            else
                sample = app.samplesAndFramesTable.Data(indices(1),1);
                app.synchronizationFile.setSynchronizationPoint(sample,event.NewData);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Video Synchronization';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.addButton = uibutton(app.Figure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'Add Sample and Frame';
            app.samplesAndFramesTable = uitable(app.Figure);
            app.samplesAndFramesTable.ColumnName = {'Sample'; 'Frame'};
            app.samplesAndFramesTable.RowName = {};
            app.samplesAndFramesTable.ColumnEditable = true;
            app.samplesAndFramesTable.CellEditCallback = createCallbackFcn(app, @samplesAndFramesTableCellEdit, true);
            app.samplesAndFramesTable.CellSelectionCallback = createCallbackFcn(app, @samplesAndFramesTableCellSelection, true);
            app.TextArea = uitextarea(app.Figure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Select a sample in the Annotation App using the timeline marker and a frame in the Movie Player and click on +'};
            app.SaveButton = uibutton(app.Figure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.removeButton = uibutton(app.Figure, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = '-';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SynchronizationDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end