classdef DataAnnotationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        filesPanel                    matlab.ui.container.Panel
        fileNamesList                 matlab.ui.control.ListBox
        groupsLabel                   matlab.ui.control.Label
        loadButton                    matlab.ui.control.Button
        preprocessingPanel            matlab.ui.container.Panel
        signalComputerVariablesTable  matlab.ui.control.Table
        signalComputerList            matlab.ui.control.ListBox
        signalsList                   matlab.ui.control.ListBox
        markersCheckBox               matlab.ui.control.CheckBox
        visualizeButton               matlab.ui.control.Button
        filesPanel_2                  matlab.ui.container.Panel
        classesList                   matlab.ui.control.ListBox
        addRangeButton                matlab.ui.control.Button
        ActionsButtonGroup            matlab.ui.container.ButtonGroup
        addRadio                      matlab.ui.control.RadioButton
        modifyRadio                   matlab.ui.control.RadioButton
        deleteRadio                   matlab.ui.control.RadioButton
        selectRangeRadio              matlab.ui.control.RadioButton
        peaksCheckBox                 matlab.ui.control.CheckBox
        saveToLabel                   matlab.ui.control.Label
        saveButton                    matlab.ui.control.Button
        plotAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Constant)
        FindPeaksRadius = 50;
        AutoFindPeakRadius = 100;
        SamplingFrequency = 200;
        SegmentHeight = 30;
    end
    
    properties (Access = private)
        %class management
        classesMap;
        
        %data loading
        currentFile;
        dataLoader;
        
        %data
        data;
        magnitude;
        columnNames;
        
        %signal computers
        preprocessingConfigurator;
                
        %annotations
        annotationSet;
        eventAnnotationsPlotter;
        rangeAnnotationsPlotter;

        %markers
        markers;
        markerHandles;
        markersPlotter;
        
        %ui   
        state;    
        rangeSelectionAxis = [];
        rangeSelection = [];
        plotHandles;
        
        %ui state
        isSelectingPeaks = 0;
        showingMarkers = 1;
    end
    
    methods (Access = private)
        
        function handleAnnotationClicked(app,source,~)
            tag = str2double(source.Tag);
            if app.state == DataAnnotatorState.kDeleteMode
                app.eventAnnotationsPlotter.deleteAnnotationAtSampleIdx(tag);
                app.rangeAnnotationsPlotter.deleteAnnotationAtSampleIdx(tag);
            elseif app.state == DataAnnotatorState.kModifyMode
                currentClass = app.classesList.Value;
                app.eventAnnotationsPlotter.modifyAnnotationToClass(uint32(tag),currentClass);
                app.rangeAnnotationsPlotter.modifyAnnotationToClass(uint32(tag),currentClass);
            end
        end
        
        function resetUI(app)
            app.loadDataTextbox.String = "";
            app.updateSelectingPeaksCheckbox();
        end
        
        function loadAll(app)
             app.loadData();
            if app.classesMap.numClasses > 0 && ~isempty(app.data)
                app.loadAnnotations();
                app.loadMarkers();
            end
        end
        
        function populateFileNamesList(app)
            extensions = {'*.mat','*.txt'};
            files = Helper.listDataFiles(extensions);
            if isempty(files)
                fprintf('%s - DataAnnotationApp',Constants.kNoDataFileFoundWarning);
            else
                app.fileNamesList.String = files;
            end
        end
        %{
        function loadPlotAxes(app)
            app.plotAxes = axes(app.UIFigure);
            app.plotAxes.Units = 'characters';
            app.plotAxes.Position  = [35 4 270 57];
            app.plotAxes.Visible = 'On';
        end
        %}
    
        function setUserClickHandle(app)
            dataCursorMode = datacursormode(app.UIFigure);
            dataCursorMode.SnapToDataVertex = 'on';
            dataCursorMode.DisplayStyle = 'window';
            dataCursorMode.Enable = 'on';
            set(dataCursorMode,'UpdateFcn',@app.handleUserClick);
        end
        
        function loadData(app)
            fileName = app.getCurrentFileName();
            if ~isempty(fileName)
                [app.data, app.columnNames] = app.dataLoader.loadData(fileName);
            end
        end
        
        function loadMarkers(app)
            if ~isempty(app.annotationSet)
                markersFileName = app.getMarkersFileName();
                app.markers = app.dataLoader.loadMarkers(markersFileName);
                
                if ~isempty(app.markers)
                    x1 = app.findFirstSynchronisationMarker();
                    x2 = app.findLastSynchronisationMarker();
                    y1 = app.findFirstSynchronisationSample();
                    y2 = app.findLastSynchronisationSample();
                    
                    a = double(y2-y1) / (x2-x1);
                    
                    for i = 1 : length(app.markers)
                        currentMarker = app.markers(i);
                        currentMarker.sample = a * (currentMarker.sample - x1) + y1;
                        app.markers(i) = currentMarker;
                    end
                end
            end
        end
  
        function plotData(app)
            if ~isempty(app.data)
                hold(app.plotAxes,'on');
                
                selectedSignals = app.preprocessingConfigurator.getSelectedSignalIdxs();
                nSignals = length(selectedSignals);
                app.plotHandles = cell(1,nSignals+1);
                
                for i = 1 : nSignals
                    signalIdx = selectedSignals(i);
                    app.plotHandles{i} = plot(app.plotAxes,app.data(:,signalIdx));
                end
                app.plotHandles{nSignals+1} = plot(app.plotAxes,app.magnitude);
            end
        end
        
        function clearAll(app)
            app.clearMarkerPlots();
            app.clearDataPlots();
            app.eventAnnotationsPlotter.clearAnnotations();
            app.rangeAnnotationsPlotter.clearAnnotations();
            cla(app.plotAxes);
        end
        
        function deleteAll(app)
            app.deleteAllMarkers();
            app.deleteAllAnnotations();
            app.deleteData();
            app.deleteRangeSelection();
        end
        
        function deleteData(app)
            app.clearDataPlots();
            app.magnitude = [];
            app.data = [];
        end
        
        function clearDataPlots(app)
            for i = 1 : length(app.plotHandles)
                plotHandle =  app.plotHandles{i};
                delete(plotHandle);
            end
            app.plotHandles = [];
        end
        
        function plotMarkers(app)
            if ~isempty(app.markers)
                app.markersPlotter.plotMarkers(app.markers,app.plotAxes,app.showingMarkers);
            end
        end
        
        function selectSampleAtLocation(app,x)
            
            if ~isempty(app.rangeSelection) && app.rangeSelection.isValidRange()
                app.deleteRangeSelection();
            end
            
            if isempty(app.rangeSelection)
                app.rangeSelection = DataAnnotatorSampleRange(x);
            else
                
            app.rangeSelection.addValue(x);
            end
            
            
            app.updateRangeSelection();
        end
        
        function updateRangeSelection(app)
            if isempty(app.rangeSelection)
                app.deleteRangeSelection();
            elseif app.rangeSelection.isValidRange()
                app.plotRangeSelection();
            end
        end
        
        function plotRangeSelection(app)
            selectionRanges = app.rangeSelection.sample1:app.rangeSelection.sample2;
            selectionMagnitude = app.magnitude(selectionRanges,:);
            app.rangeSelectionAxis = plot(app.plotAxes,selectionRanges,selectionMagnitude,'Color','red');
        end
        
        function deleteRangeSelection(app)
            if ~isempty(app.rangeSelectionAxis)
                delete(app.rangeSelectionAxis);
                app.rangeSelectionAxis = [];
                app.rangeSelection = [];
            end
        end
        
        function addPeakAtLocation(app,x)
            peakIdx = app.findPeakIdxNearLocation(x);
            
            if peakIdx > 0
                app.addSampleAtLocation(peakIdx);
            end
        end
        
        function addSampleAtLocation(app,x)
            y = app.magnitude(x);
            currentClass = app.getSelectedClass();
            app.eventAnnotationsPlotter.addAnnotation(app.plotAxes,x,y,currentClass);
        end
        
        function updateLoadDataTextbox(app,~,~)
            app.loadDataTextbox.String = sprintf('data size: %d x %d',size(app.data,1),size(app.data,2));
        end
 
        function populateClassesList(app)
            classes = app.classesMap.classesList;
            classes{end+1} = app.classesMap.synchronisationStr;
            app.classesList.String = classes;
        end
        
        function deleteAllMarkers(app)
            app.clearMarkerPlots();
            app.markers = [];
        end
        
        function clearMarkerPlots(app)
            app.markersPlotter.deleteMarkers();
        end
        
        function deleteAllAnnotations(app)
            app.eventAnnotationsPlotter.clearAnnotations();
            app.rangeAnnotationsPlotter.clearAnnotations();
            app.annotationSet = [];
        end
                
        function addCurrentRange(app)
            if ~isempty(app.rangeSelection) && app.rangeSelection.isValidRange()
                label = app.getSelectedClass();
                rangeAnnotation = RangeAnnotation(app.rangeSelection.sample1,...
                    app.rangeSelection.sample2,label);
                app.rangeAnnotationsPlotter.plotAnnotation(app.plotAxes, rangeAnnotation);
            end
        end
        
        function updateFileName(app)
            app.currentFile = app.fileNamesList.Value;
        end
        
        function markersFileName = getMarkersFileName(app)
            fileName = app.getCurrentFileNameNoExtension();
            markersFileName = sprintf('%s-markers.edl',fileName);
        end
        
        function annotationsFileName = getAnnotationsFileName(app)
            fileName = app.getCurrentFileNameNoExtension();
            annotationsFileName = Helper.addAnnotationsFileExtension(fileName);
        end
        
        function fileName = getCurrentFileName(app)
            if isempty(app.fileNamesList.String)
                fileName = [];
            else
                fileName = app.fileNamesList.String{app.currentFile};
            end
        end
        
        function fileName = getCurrentFileNameNoExtension(app)
            dataFileName = app.getCurrentFileName();
            fileName = Helper.removeFileExtension(dataFileName);
        end
        
        function saveAnnotations(app)
            eventAnnotations = app.eventAnnotationsPlotter.getAnnotations();
            rangeAnnotations = app.rangeAnnotationsPlotter.getAnnotations();
            annotations = AnnotationSet(eventAnnotations,rangeAnnotations);
            if ~isempty(eventAnnotations)
                fileName = app.getAnnotationsFileName();
                app.dataLoader.saveAnnotations(annotations,fileName);
            end
        end
        
        function loadAnnotations(app)
            fileName = app.getAnnotationsFileName();
            app.annotationSet = app.dataLoader.loadAnnotations(fileName);
        end
        
        function plotAnnotations(app)
            if ~isempty(app.annotationSet) && ~isempty(app.magnitude)
                
                app.eventAnnotationsPlotter.plotAnnotations(app.plotAxes,app.annotationSet.eventAnnotations,app.magnitude);
                app.rangeAnnotationsPlotter.plotAnnotations(app.plotAxes,app.annotationSet.rangeAnnotations);
            end
        end
                
        function peakIdx = findPeakIdxNearLocation(app,idx)
            
            [~, peakIdx] = max(app.magnitude(idx-app.FindPeaksRadius:idx+app.FindPeaksRadius));
            peakIdx = int32(peakIdx + idx - app.FindPeaksRadius - 1);
        end
        
        
        function computeMagnitude(app)
            signalComputer = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
            
            if ~isempty(signalComputer)
                app.magnitude = signalComputer.compute(app.data);
            end
        end
        
        function toggleMarkersVisibility(app,visible)
            visibleStr = MarkersPlotter.getVisibleStr(visible);
            for i = 1 : length(app.markerHandles)
                markerHandle = app.markerHandles(i);
                markerHandle.textHandle.Visible = visibleStr;
                markerHandle.lineHandle.Visible = visibleStr;
            end
        end
    
        %% UI
        function class = getSelectedClass(app)
            class = uint8(app.classesList.Value);
        end

        function updateSelectingPeaksCheckbox(app)
            app.peaksCheckBox.Value = app.isSelectingPeaks;
        end
        
        function shouldSelectPeaks = getShouldSelectPeaks(app)
            shouldSelectPeaks = app.peaksCheckBox.Value;
        end
    
            
            function outputTxt = handleUserClick(app,src,~)
            pos = get(src,'Position');
            x = pos(1);
            y = pos(2);
            xStr = num2str(x,7);
            yStr = num2str(y,7);
            outputTxt = {['X: ',xStr],['Y: ',yStr]};
            
            fprintf('%d\n',x);
            
            if app.state == DataAnnotatorState.kAddMode
                if app.isSelectingPeaks
                    app.addPeakAtLocation(x);
                else
                    app.addSampleAtLocation(x);
                end
            elseif app.state == DataAnnotatorState.kSelectSamplesMode
                app.selectSampleAtLocation(x);
            end
            end
        
                function handleStateChanged(app,~,~)
            
            cursorModeHandle = datacursormode(app.UIFigure);
            cursorModeHandle.Enable = 'on';
            
            if app.state == DataAnnotatorState.kSelectSamplesMode
                app.deleteRangeSelection();
            end
            
            switch app.stateButtonGroup.Selectedappect
                case (app.addRadio)
                    app.state = DataAnnotatorState.kAddMode;
                case (app.modifyRadio)
                    app.state = DataAnnotatorState.kModifyMode;
                    cursorModeHandle.Enable = 'off';
                case (app.deleteRadio)
                    app.state = DataAnnotatorState.kDeleteMode;
                    cursorModeHandle.Enable = 'off';
                case (app.selectRangeRadio)
                    app.state = DataAnnotatorState.kSelectSamplesMode;
            end
        end
        
     %% Helper methods
        function idx = findIdxOfValue(~,valueArray,startIdx,value)
            idx = uint32(-1);
            for i = startIdx : length(valueArray)
                if value == valueArray(i)
                    idx = i;
                    break;
                end
            end
        end
        
         function firstSynchronisatonSample = findFirstSynchronisationSample(app)
            firstSynchronisatonSample = -1;
            eventAnnotations = app.annotationSet.eventAnnotations;
            for i = 1 : length(eventAnnotations)
                manualAnnotation = eventAnnotations(i);
                if manualAnnotation.label == app.classesMap.synchronisationClass
                    firstSynchronisatonSample = manualAnnotation.sample;
                    break;
                end
            end
        end
        
        function lastSynchronisatonSample = findLastSynchronisationSample(app)
            lastSynchronisatonSample = -1;
            eventAnnotations = app.annotationSet.eventAnnotations;
            for i = length(eventAnnotations) : -1 : 1
                manualAnnotation = eventAnnotations(i);
                if manualAnnotation.label == app.classesMap.synchronisationClass
                    lastSynchronisatonSample = manualAnnotation.sample;
                    break;
                end
            end
        end
        
        function firstSynchronisationMarker = findFirstSynchronisationMarker(app)
            firstSynchronisationMarker = -1;
            for i = 1 : length(app.markers)
                currentMarker = app.markers(i);
                if currentMarker.label == Constants.SynchronisatonMarker
                    firstSynchronisationMarker = currentMarker.sample;
                    break;
                end
            end
        end
        
        function lastSynchronisationMarker = findLastSynchronisationMarker(app)
            lastSynchronisationMarker = -1;
            for i = length(app.markers) : -1 : 1
                currentMarker = app.markers(i);
                if currentMarker.label == Constants.SynchronisatonMarker
                    lastSynchronisationMarker = currentMarker.sample;
                    break;
                end
            end
        end
    
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        clear ClassesMap;
            app.classesMap = ClassesMap.instance();
            app.dataLoader = DataLoader();
            app.markersPlotter = MarkersPlotter();
            
            app.eventAnnotationsPlotter = EventAnnotationsPlotter(app.classesMap);
            app.eventAnnotationsPlotter.delegate = app;
            
            app.rangeAnnotationsPlotter = RangeAnnotationsPlotter(app.classesMap);
            app.rangeAnnotationsPlotter.delegate = app;
            
            app.currentFile = 1;
            app.state = DataAnnotatorState.kAddMode;
             
            %app.loadPlotAxes();
            app.setUserClickHandle();
            app.populateFileNamesList();
            app.populateClassesList();
            
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                app.signalsList,...
                app.signalComputerList,...
                app.signalComputerVariablesTable);
            
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            
            app.deleteAll();
            app.loadAll();
            
            if ~isempty(app.columnNames)
            app.preprocessingConfigurator.setColumnNames(app.columnNames);
            end
            
            if ~isempty(app.data)
                app.updateLoadDataTextbox();
            end
        end

        % Button pushed function: visualizeButton
        function visualizeButtonPushed(app, event)
            
            if ~isempty(app.data)
                app.clearAll();
                app.computeMagnitude();
                app.plotData();
                app.plotAnnotations();
                app.plotMarkers();
            end
        end

        % Value changed function: peaksCheckBox
        function peaksCheckBoxValueChanged(app, event)
            value = app.peaksCheckBox.Value;
            
            app.isSelectingPeaks = value;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            
            app.saveAnnotations();
        end

        % Value changed function: markersCheckBox
        function markersCheckBoxValueChanged(app, event)
            value = app.markersCheckBox.Value;
            
            app.showingMarkers = value;
            app.markersPlotter.toggleMarkersVisibility(app.showingMarkers);
        end

        % Value changed function: fileNamesList
        function fileNamesListValueChanged(app, event)
            value = app.fileNamesList.Value;
            app.currentFile = value;
        end

        % Button pushed function: addRangeButton
        function addRangeButtonPushed(app, event)
            
            app.addCurrentRange();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.filesPanel = uipanel(app.UIFigure);
            app.filesPanel.Title = 'Files';
            app.fileNamesList = uilistbox(app.filesPanel);
            app.fileNamesList.Items = {};
            app.fileNamesList.Multiselect = 'on';
            app.fileNamesList.ValueChangedFcn = createCallbackFcn(app, @fileNamesListValueChanged, true);
            app.fileNamesList.Value = {};
            app.groupsLabel = uilabel(app.filesPanel);
            app.groupsLabel.Text = '';
            app.loadButton = uibutton(app.filesPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'Load';
            app.preprocessingPanel = uipanel(app.UIFigure);
            app.preprocessingPanel.Title = 'Visualization';
            app.signalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.signalComputerVariablesTable.ColumnName = {'Column 1'; 'Column 2'};
            app.signalComputerVariablesTable.RowName = {};
            app.signalComputerList = uilistbox(app.preprocessingPanel);
            app.signalComputerList.Items = {};
            app.signalComputerList.Value = {};
            app.signalsList = uilistbox(app.preprocessingPanel);
            app.signalsList.Items = {};
            app.signalsList.Multiselect = 'on';
            app.signalsList.Value = {};
            app.markersCheckBox = uicheckbox(app.preprocessingPanel);
            app.markersCheckBox.ValueChangedFcn = createCallbackFcn(app, @markersCheckBoxValueChanged, true);
            app.markersCheckBox.Text = 'Markers';
            app.markersCheckBox.Value = true;
            app.visualizeButton = uibutton(app.preprocessingPanel, 'push');
            app.visualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualizeButton.Text = 'Visualize';
            app.filesPanel_2 = uipanel(app.UIFigure);
            app.filesPanel_2.Title = 'Annotations';
            app.classesList = uilistbox(app.filesPanel_2);
            app.classesList.Items = {};
            app.classesList.Value = {};
            app.addRangeButton = uibutton(app.filesPanel_2, 'push');
            app.addRangeButton.ButtonPushedFcn = createCallbackFcn(app, @addRangeButtonPushed, true);
            app.addRangeButton.Text = 'Add Range';
            app.ActionsButtonGroup = uibuttongroup(app.filesPanel_2);
            app.ActionsButtonGroup.Title = 'Actions';
            app.addRadio = uiradiobutton(app.ActionsButtonGroup);
            app.addRadio.Text = 'Add';
            app.addRadio.Value = true;
            app.modifyRadio = uiradiobutton(app.ActionsButtonGroup);
            app.modifyRadio.Text = 'Modify';
            app.deleteRadio = uiradiobutton(app.ActionsButtonGroup);
            app.deleteRadio.Text = 'Delete';
            app.selectRangeRadio = uiradiobutton(app.ActionsButtonGroup);
            app.selectRangeRadio.Text = 'Select Range';
            app.peaksCheckBox = uicheckbox(app.ActionsButtonGroup);
            app.peaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @peaksCheckBoxValueChanged, true);
            app.peaksCheckBox.Text = 'Peaks';
            app.saveToLabel = uilabel(app.UIFigure);
            app.saveToLabel.Text = '';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save';
            app.plotAxes = uiaxes(app.UIFigure);
            title(app.plotAxes, '')
            xlabel(app.plotAxes, 'X')
            ylabel(app.plotAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataAnnotationApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end