classdef AnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                    matlab.ui.Figure
        segmentsSourceButtonGroup     matlab.ui.container.ButtonGroup
        manualSegmentationRadio       matlab.ui.control.RadioButton
        automaticSegmentationRadio    matlab.ui.control.RadioButton
        createButton                  matlab.ui.control.Button
        segmentationPanel             matlab.ui.container.Panel
        segmentationVariablesTable    matlab.ui.control.Table
        segmentationList              matlab.ui.control.ListBox
        segmentationLabel             matlab.ui.control.Label
        plotPanel                     matlab.ui.container.Panel
        visualizationPanel            matlab.ui.container.Panel
        algorithmVariablesTableVisualization  matlab.ui.control.Table
        algorithmsListVisualization   matlab.ui.control.ListBox
        signalsListVisualization      matlab.ui.control.ListBox
        visualizationClassesList      matlab.ui.control.ListBox
        PlotStyleButtonGroup          matlab.ui.container.ButtonGroup
        overlappingPlotRadio          matlab.ui.control.RadioButton
        sequentialPlotRadio           matlab.ui.control.RadioButton
        visualizeButton               matlab.ui.control.Button
        plotToolbar                   matlab.ui.container.ButtonGroup
        zoomButton                    matlab.ui.control.ToggleButton
        panButton                     matlab.ui.control.ToggleButton
        preprocessingPanel            matlab.ui.container.Panel
        preprocessingAlgorithmVariablesTable  matlab.ui.control.Table
        preprocessingAlgorithmsList   matlab.ui.control.ListBox
        preprocessingSignalsList      matlab.ui.control.ListBox
        sameScaleCheckbox             matlab.ui.control.CheckBox
        filesPanel                    matlab.ui.container.Panel
        inputFilesList                matlab.ui.control.ListBox
        selectAllInputFilesList       matlab.ui.control.CheckBox
        manualAnnotationsTypePanel    matlab.ui.container.ButtonGroup
        includeEventsCheckBox         matlab.ui.control.CheckBox
        includeRangesCheckBox         matlab.ui.control.CheckBox
        resetZoomButton               matlab.ui.control.Button
        clearCacheButton              matlab.ui.control.Button
        Statistics                    matlab.ui.container.Panel
        statisticsTable               matlab.ui.control.Table
        eventDetectionPanel           matlab.ui.container.Panel
        eventDetectionPreprocessingAlgorithmVariablesTable  matlab.ui.control.Table
        eventDetectionPreprocessingAlgorithmsList  matlab.ui.control.ListBox
        eventDetectionPreprocessingSignalsList  matlab.ui.control.ListBox
        eventDetectionVariablesTable  matlab.ui.control.Table
        eventDetectionList            matlab.ui.control.ListBox
        annotationsPanel              matlab.ui.container.Panel
        annotationMappingList         matlab.ui.control.ListBox
        annotationMappingTable        matlab.ui.control.Table
        labelingPanel                 matlab.ui.container.Panel
        labelingStrategiesList        matlab.ui.control.ListBox
        labelingVariablesTable        matlab.ui.control.Table
    end

    
    properties (Access = private)
        % chain
        arcChain;
        annotationChain;
        
        %classes
        annotations;
        
        %runtime variables
        manualSegmentation;
        fileLoader;
        annotationsLoader;
        labeler;
        currentLabeling;
        currentSegmentation;
        
        %data
        segments;
        groupedSegments;
        filteredSegments;
        processedAnnotations;
        
        %preprocessing
        preprocessingConfigurator;
        eventDetectionConfigurator;
        segmentationConfigurator;
        labelingConfigurator;
        labelingAlgorithms;
        
        %ui
        labelMappingConfigurator;
        preprocessingConfiguratorVisualization;
        isManualMode;
        
        %ui plotting
        segmentsPlotter;
        uiHandles;
    end
    
    methods (Access = private)
        
        %% UI
        function showLines = getShowLinesCheckbox(app)
            showLines = app.showLinesCheckbox.Value;
        end
        
        function includeEvents = getIncludeEvents(app)
            includeEvents = app.includeEventsCheckBox.Value;
        end
        
        function includeRanges = getIncludeRanges(app)
            includeRanges = app.includeRangesCheckBox.Value;
        end
        
        function resetSegmentsLabel(app)
            app.segmentationLabel.Text = "";
        end
        
        function updateSegmentsLabel(app)
            str = sprintf('%d files.',length(app.segments));
            app.segmentationLabel.Text = str;
        end
        
        function value = getSameScale(app)
            value = app.sameScaleCheckbox.Value;
        end
        
        function updateGroupsList(app)
            nClasses = length(app.groupedSegments);
            classesStr = cell(1,nClasses);
            
            for i = 1 : app.currentLabeling.numClasses
                className = app.currentLabeling.classNames{i};
                nSegments = length(app.groupedSegments{i});
                str = sprintf('%s: %d',className,nSegments);
                classesStr{i} = str;
            end
            
            nSegments = length(app.groupedSegments{nClasses});
            classesStr{nClasses} = sprintf('%s: %d',Labeling.kNullClassStr,nSegments);
            app.visualizationClassesList.Items = classesStr;
            app.visualizationClassesList.Value = app.visualizationClassesList.Items;
        end
        
        function updateEventDetectionTablesVisibility(app)
            if app.isManualMode
                app.preprocessingPanel.Visible = 'Off';
                app.eventDetectionPanel.Visible = 'Off';
                app.manualAnnotationsTypePanel.Visible = 'On';
                
            else
                app.preprocessingPanel.Visible = 'On';
                app.eventDetectionPanel.Visible = 'On';
                app.manualAnnotationsTypePanel.Visible = 'Off';
            end
        end
        
        function updateLabelingPanelVisibility(app)
            if app.isManualMode
                app.labelingPanel.Visible = 'Off';
            else
                app.labelingPanel.Visible = 'On';
            end
        end
        
        function idxs = getSelectedClassIdxs(app)
            idxStr = app.visualizationClassesList.Value;
            [~,idxs] = ismember(idxStr,app.visualizationClassesList.Items);
            idxs = sort(idxs);
        end
        
        
        %% methods
        function loadUIConfigurators(app,labelMappings)
            
            %label mapping
            app.labelMappingConfigurator = LabelMappingConfigurator(...
                labelMappings,...
                app.annotationMappingList,...
                app.annotationMappingTable);
            
            %preprocessing
            preprocessingAlgorithms = Palette.PreprocessingAlgorithms();
            
            signalNames = DataLoader.LoadSignalNames();
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                signalNames,...
                preprocessingAlgorithms,...
                app.preprocessingSignalsList,...
                app.preprocessingAlgorithmsList,...
                app.preprocessingAlgorithmVariablesTable);
            
            app.preprocessingConfiguratorVisualization = PreprocessingConfigurator(...
                signalNames,...
                preprocessingAlgorithms,...
                app.signalsListVisualization,...
                app.algorithmsListVisualization,...
                app.algorithmVariablesTableVisualization);
            
            app.signalsListVisualization.Value = app.signalsListVisualization.Items{1};
            
            %event detection
            eventDetectionUIElements = EventDetectionConfiguratorUIElements();
            eventDetectionUIElements.preprocessingSignalsList = app.eventDetectionPreprocessingSignalsList;
            eventDetectionUIElements.preprocessingAlgorithmsList = app.eventDetectionPreprocessingAlgorithmsList;
            eventDetectionUIElements.preprocessingAlgorithmsVariablesTable = app.eventDetectionPreprocessingAlgorithmVariablesTable;
            eventDetectionUIElements.eventDetectionList = app.eventDetectionList;
            eventDetectionUIElements.eventDetectionVariablesTable = app.eventDetectionVariablesTable;
            
            eventDetectors = Palette.EventDetectionAlgorithms();
            
            app.eventDetectionConfigurator = EventDetectionConfigurator(...
                signalNames, preprocessingAlgorithms, eventDetectors,...
                eventDetectionUIElements,app);
            
            % segmentation
            segmentationStrategies = {app.manualSegmentation};
            
            app.segmentationConfigurator = AlgorithmConfigurator(...
                segmentationStrategies,...
                app.segmentationList,...
                app.segmentationVariablesTable,...
                app);
            
            % labeling
            app.labelingAlgorithms = {EventSegmentsLabeler,RangeSegmentsLabeler};
            app.labelingConfigurator = AlgorithmConfigurator(...
                {EventSegmentsLabeler},...
                app.labelingStrategiesList,...
                app.labelingVariablesTable);
        end
        
        function plotData(app)
            
            classNames = app.currentLabeling.classNames;
            nClasses = length(app.currentLabeling.classNames);
            classNames{nClasses+1} = Labeling.kNullClassStr;
            
            selectedClassesIdxs = app.getSelectedClassIdxs();
            groupNames = classNames(selectedClassesIdxs);
            
            app.segmentsPlotter.sameScale = app.getSameScale();
            
            app.segmentsPlotter.sequentialSegments = app.sequentialPlotRadio.Value;
            
            app.segmentsPlotter.plotSegments(app.filteredSegments,groupNames);
        end
        
        function applySignalAlgorithms(app)
            
            filterAlgorithm = app.preprocessingConfiguratorVisualization.createPreprocessingAlgorithmWithUIParameters();
            if isempty(filterAlgorithm)
                filterAlgorithm = app.preprocessingConfiguratorVisualization.createAxisSelectorWithUIParameters();
            end
            
            selectedClassesIdxs = app.getSelectedClassIdxs();
            
            nSelectedClasses = length(selectedClassesIdxs);
            
            app.filteredSegments = cell(1,nSelectedClasses);
            
            for i = 1 : nSelectedClasses
                currentClassIdx = selectedClassesIdxs(i);
                segmentsCurrentGroup = app.groupedSegments{currentClassIdx};
                segmentsArray = repmat(Segment(),1,length(segmentsCurrentGroup));
                
                for j = 1 : length(segmentsArray)
                    segment = segmentsCurrentGroup(j);
                    filteredData = filterAlgorithm.compute(segment.data);
                    filteredSegment = Segment(segment.file,filteredData,segment.label,segment.eventIdx);
                    segmentsArray(j) = filteredSegment;
                end
                app.filteredSegments{i} = segmentsArray;
            end
        end
        
        function buildAnnotationChain(app)
            app.annotationsLoader = AnnotationLoader();
            labelMapper = app.labelMappingConfigurator.createLabelMapperFromUI();
            
            app.currentLabeling = labelMapper.targetLabeling;
            
            app.annotationsLoader.annotations = app.annotations;
            annotationsAlgorithms = {app.annotationsLoader,labelMapper};
            app.annotationChain = Algorithm.AlgorithmWithSequence(annotationsAlgorithms);
        end
        
        function buildManualChain(app)
            app.fileLoader = FileLoader();
            app.currentSegmentation = app.segmentationConfigurator.createAlgorithmWithUIParameters();
            app.currentSegmentation.includeEvents = app.getIncludeEvents();
            app.currentSegmentation.includeRanges = app.getIncludeRanges();
            
            app.arcChain = Algorithm.AlgorithmWithSequence({app.fileLoader,app.currentSegmentation});
        end
        
        function buildAutomaticChain(app)
            app.fileLoader = FileLoader();
            app.labeler = app.labelingConfigurator.createAlgorithmWithUIParameters();
            
            preprocessor = app.preprocessingConfigurator.createPreprocessingAlgorithmWithUIParameters();
            eventDetector = app.eventDetectionConfigurator.createEventDetectorWithUIParameters();
            
            propertyGetter = PropertyGetter('data');
            
            algorithmSequence = {app.fileLoader,propertyGetter};
            
            if ~isempty(preprocessor)
                algorithmSequence{end+1} = preprocessor;
            end
            
            if ~isempty(eventDetector)
                algorithmSequence{end+1} = eventDetector;
            end
            
            app.currentSegmentation = app.segmentationConfigurator.createAlgorithmWithUIParameters();
            
            algorithmSequence{end+1} = app.currentSegmentation;
            algorithmSequence{end+1} = app.labeler;
            
            app.arcChain = Algorithm.AlgorithmWithSequence(algorithmSequence);
        end
        
        function buildChainFromUI(app)
            
            app.buildAnnotationChain();
            
            if app.isManualMode
                app.buildManualChain();
            else
                app.buildAutomaticChain();
            end
        end
        
        function validData = checkValidComputedSegments(app)
            validData = true;
            for i = 1 : length(app.filteredSegments)
                segmentsCurrentPlayer = app.filteredSegments{i};
                for j = 1 : length(segmentsCurrentPlayer)
                    segment = segmentsCurrentPlayer(j);
                    if isempty(segment) || isempty(segment.data)
                        validData = false;
                        break;
                    end
                end
                if validData == false
                    break;
                end
            end
        end
        
        function executionSucceeded = executeChain(app)
            executionSucceeded = true;
            [~,selectedIdxs] = ismember(app.inputFilesList.Value,app.inputFilesList.Items);
            selectedIdxs = sort(selectedIdxs);
            
            nFiles = length(selectedIdxs);
            app.segments = cell(1,nFiles);
            
            waitBar = waitbar(0,'Executing Chain');
            
            app.processedAnnotations = repmat(AnnotationSet,1,nFiles);
            try
                for i = 1 : nFiles
                    selectedIdx = selectedIdxs(i);
                    fileName = app.inputFilesList.Items{selectedIdx};
                    
                    waitBarMsg = sprintf('Executing Chain for file %s ...',fileName);
                    waitbar(i/nFiles,waitBar,waitBarMsg);
                    
                    app.fileLoader.fileName = fileName;
                    app.annotationsLoader.fileName = fileName;
                    [app.processedAnnotations(i),~,annotationChainString] = Algorithm.ExecuteChain(app.annotationChain,'start',true);
                    if app.isManualMode
                        app.currentSegmentation.manualAnnotations = app.processedAnnotations(i);
                    else
                        app.labeler.manualAnnotations = app.processedAnnotations(i);
                    end
                    
                    app.segments{i} = Algorithm.ExecuteChain(app.arcChain,'start',true,annotationChainString);
                end
            catch exception
                warning(Constants.kAlgorithmExecutionFailedWarning);
                msgText = getReport(exception);
                warning(msgText);
                executionSucceeded = false;
            end
            close(waitBar)
        end
        
        function populateFilesList(app)
            
            fileNames = Helper.ListDataFiles();
            app.inputFilesList.Items = fileNames(1:end);
            app.inputFilesList.Value = app.inputFilesList.Items;
        end
        
        function meanRangeDurations = calculateMeanRangeDurations(app)
            nGroups = length(app.groupedSegments);
            meanRangeDurations = zeros(1,nGroups-1);
            for i = 1 : nGroups-1
                segmentsCurrentClass = app.groupedSegments{i};
                if ~isempty(segmentsCurrentClass)
                    meanRangeDurations(i) = mean([segmentsCurrentClass.endSample] - [segmentsCurrentClass.startSample] + 1);
                end
            end
        end
        
        function updateStatisticsTable(app)
            
            nClasses = length(app.groupedSegments);
            data = cell(nClasses+1,2);
            meanSegmentDurations = app.calculateMeanRangeDurations();
            
            sumRelevantInstances = 0;
            for i = 1 : app.currentLabeling.numClasses
                classLabel = app.currentLabeling.classNames{i};
                data{i,1} = classLabel;
                data{i,2} = length(app.groupedSegments{i});
                data{i,3} = sprintf('%.1f',meanSegmentDurations(i));
                sumRelevantInstances = sumRelevantInstances + length(app.groupedSegments{i});
            end
            
            data{nClasses,1} = Labeling.kNullClassStr;
            data{nClasses,2} = length(app.groupedSegments{nClasses});
            data{nClasses,3} = '-';
            
            data{nClasses+1,1} = 'Total Relevant';
            data{nClasses+1,2} = sumRelevantInstances;
            data{nClasses+1,3} = sprintf('%.1f',mean(meanSegmentDurations));
            
            app.statisticsTable.Data = data;
        end
        
        function segmentationAlgorithms = generateSegmentationAlgorithms(app)
            if app.isManualMode
                segmentationAlgorithms = {app.manualSegmentation};
            else
                eventSegmentation = EventSegmentation();
                slidingWindowSegmentation = SlidingWindowSegmentation();
                segmentationAlgorithms = {eventSegmentation,slidingWindowSegmentation};
            end
        end
        
        %% Setup path
        function setupPath(~)
            fullPath = pwd;
            directories = split(fullPath,'/');
            if(strcmp('2-Analysis',directories{end}))
                cd ..;
            end
            addpath(genpath('./'));
        end
    end
    
    methods (Access = public)
        
        function handleAlgorithmChanged(app, algorithm, algorithmConfigurator)
            if algorithmConfigurator == app.eventDetectionConfigurator
                
                segmentationAlgorithms = app.generateSegmentationAlgorithms();
                
                app.segmentationConfigurator.algorithms = ...
                    Palette.FilterAlgorithmsToInputType(segmentationAlgorithms,...
                    algorithm.outputPort);
                
                app.segmentationConfigurator.reloadUI();
                
            elseif algorithmConfigurator == app.segmentationConfigurator
                
                if isempty(algorithm)
                   app.labelingConfigurator.algorithms = app.labelingAlgorithms;
                elseif isa(algorithm,'EventSegmentation')
                    app.labelingConfigurator.algorithms = {EventSegmentsLabeler};
                else
                    app.labelingConfigurator.algorithms = {RangeSegmentsLabeler};
                end
                
                app.labelingConfigurator.reloadUI();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mainFigure.Visible = false;
            
            app.setupPath();
            
            close all;
            clear Cache;
            movegui(app.mainFigure,'center');
            app.mainFigure.Visible = true;
            
            labelMappings = DataLoader.LoadAllLabelMappings();
            
            %annotations
            defaultLabeling = labelMappings(1).sourceLabeling;
            app.annotations = DataLoader.LoadAllAnnotations(defaultLabeling);
            
            app.manualSegmentation = ManualSegmentation();
            app.isManualMode = true;
            
            app.loadUIConfigurators(labelMappings);
            
            app.populateFilesList();
            
            app.segmentsPlotter = AnalysisSegmentsPlotter(app.plotPanel);
        end

        % Button pushed function: createButton
        function executeButtonPushed(app, event)
            if(~app.isManualMode || app.getIncludeEvents() || app.getIncludeRanges())

                app.groupedSegments = [];
                
                app.buildChainFromUI();
                
                executionSucceeded = app.executeChain();
                
                if executionSucceeded
                    app.updateSegmentsLabel();
                    
                    segmentsGrouper = SegmentsGrouper();
                    app.groupedSegments = segmentsGrouper.groupSegments(app.segments,app.currentLabeling.numClasses);
                    app.updateGroupsList();
                    app.updateStatisticsTable();
                end
            end
        end

        % Button pushed function: visualizeButton
        function visualizeButtonPushed(app, event)

            if ~isempty(app.groupedSegments)
                app.applySignalAlgorithms();
                validData = app.checkValidComputedSegments();
                if validData
                    app.plotData();
                else
                    fprintf('SignalExplorerApp - %s\n',Constants.kInvalidFilterComputedError);
                end
            end
        end

        % Selection changed function: plotToolbar
        function handleToolbarButtonChanged(app, event)
            selectedButton = app.plotToolbar.SelectedObject;
            
            str = Helper.GetOnOffStringLowerCase(true);
            if(selectedButton == app.zoomButton)
                app.segmentsPlotter.setZoom(str);
            else
                app.segmentsPlotter.setPan(str);
            end
        end

        % Selection changed function: segmentsSourceButtonGroup
        function segmentsSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.segmentsSourceButtonGroup.SelectedObject;
            if(selectedButton == app.manualSegmentationRadio)
                app.segmentationPanel.Title = '2-Segmentation';
                app.isManualMode = true;
            else
                app.segmentationPanel.Title = '4-Segmentation';
                app.isManualMode = false;
            end
            
            app.updateEventDetectionTablesVisibility();
            app.updateLabelingPanelVisibility();
            
            app.segmentationConfigurator.algorithms = app.generateSegmentationAlgorithms();
            app.segmentationConfigurator.reloadUI();
        end

        % Value changed function: sameScaleCheckbox
        function sameScaleCheckboxValueChanged(app, event)
            sameScale = app.sameScaleCheckbox.Value;
            if(~isempty(app.segmentsPlotter))
                app.segmentsPlotter.setSameScale(sameScale);
            end
        end

        % Value changed function: selectAllInputFilesList
        function selectAllInputFilesListValueChanged(app, event)
            value = app.selectAllInputFilesList.Value;
            if(value)
                app.inputFilesList.Value = app.inputFilesList.Items;
            end
        end

        % Button pushed function: resetZoomButton
        function resetZoomButtonPushed(app, event)
            app.segmentsPlotter.resetZoom();
        end

        % Button pushed function: clearCacheButton
        function clearCacheButtonPushed(app, event)
            Cache.ClearAllCacheFiles();
            clear Cache;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'Analysis App';
            app.segmentsSourceButtonGroup = uibuttongroup(app.mainFigure);
            app.segmentsSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @segmentsSourceButtonGroupSelectionChanged, true);
            app.segmentsSourceButtonGroup.Title = '1a-Data';
            app.manualSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.manualSegmentationRadio.Text = 'Manual annotations';
            app.manualSegmentationRadio.Value = true;
            app.automaticSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.automaticSegmentationRadio.Text = 'Automatic segmentation';
            app.createButton = uibutton(app.mainFigure, 'push');
            app.createButton.ButtonPushedFcn = createCallbackFcn(app, @executeButtonPushed, true);
            app.createButton.Text = 'Execute';
            app.segmentationPanel = uipanel(app.mainFigure);
            app.segmentationPanel.Title = '2-Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true];
            app.segmentationList = uilistbox(app.segmentationPanel);
            app.segmentationList.Items = {};
            app.segmentationList.Value = {};
            app.segmentationLabel = uilabel(app.segmentationPanel);
            app.segmentationLabel.Text = '';
            app.plotPanel = uipanel(app.mainFigure);
            app.visualizationPanel = uipanel(app.mainFigure);
            app.visualizationPanel.Title = 'Visualization';
            app.algorithmVariablesTableVisualization = uitable(app.visualizationPanel);
            app.algorithmVariablesTableVisualization.ColumnName = {'Variable'; 'Value'};
            app.algorithmVariablesTableVisualization.RowName = {};
            app.algorithmVariablesTableVisualization.ColumnEditable = [false true];
            app.algorithmsListVisualization = uilistbox(app.visualizationPanel);
            app.algorithmsListVisualization.Items = {};
            app.algorithmsListVisualization.Value = {};
            app.signalsListVisualization = uilistbox(app.visualizationPanel);
            app.signalsListVisualization.Items = {};
            app.signalsListVisualization.Multiselect = 'on';
            app.signalsListVisualization.Value = {};
            app.visualizationClassesList = uilistbox(app.visualizationPanel);
            app.visualizationClassesList.Items = {};
            app.visualizationClassesList.Multiselect = 'on';
            app.visualizationClassesList.Value = {};
            app.PlotStyleButtonGroup = uibuttongroup(app.visualizationPanel);
            app.PlotStyleButtonGroup.Title = 'Plot Style';
            app.overlappingPlotRadio = uiradiobutton(app.PlotStyleButtonGroup);
            app.overlappingPlotRadio.Text = 'Overlapping';
            app.overlappingPlotRadio.Value = true;
            app.sequentialPlotRadio = uiradiobutton(app.PlotStyleButtonGroup);
            app.sequentialPlotRadio.Text = 'Sequential';
            app.visualizeButton = uibutton(app.visualizationPanel, 'push');
            app.visualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualizeButton.Text = 'Visualize';
            app.plotToolbar = uibuttongroup(app.mainFigure);
            app.plotToolbar.SelectionChangedFcn = createCallbackFcn(app, @handleToolbarButtonChanged, true);
            app.zoomButton = uitogglebutton(app.plotToolbar);
            app.zoomButton.Text = 'Zoom';
            app.panButton = uitogglebutton(app.plotToolbar);
            app.panButton.Text = 'Pan';
            app.panButton.Value = true;
            app.preprocessingPanel = uipanel(app.mainFigure);
            app.preprocessingPanel.Title = '2-Preprocessing';
            app.preprocessingPanel.Visible = 'off';
            app.preprocessingAlgorithmVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingAlgorithmVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingAlgorithmVariablesTable.RowName = {};
            app.preprocessingAlgorithmVariablesTable.ColumnEditable = [false true];
            app.preprocessingAlgorithmsList = uilistbox(app.preprocessingPanel);
            app.preprocessingAlgorithmsList.Items = {};
            app.preprocessingAlgorithmsList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.sameScaleCheckbox = uicheckbox(app.mainFigure);
            app.sameScaleCheckbox.ValueChangedFcn = createCallbackFcn(app, @sameScaleCheckboxValueChanged, true);
            app.sameScaleCheckbox.Text = 'Same scale';
            app.sameScaleCheckbox.Value = true;
            app.filesPanel = uipanel(app.mainFigure);
            app.filesPanel.Title = 'Input Files';
            app.inputFilesList = uilistbox(app.filesPanel);
            app.inputFilesList.Items = {};
            app.inputFilesList.Multiselect = 'on';
            app.inputFilesList.Value = {};
            app.selectAllInputFilesList = uicheckbox(app.filesPanel);
            app.selectAllInputFilesList.ValueChangedFcn = createCallbackFcn(app, @selectAllInputFilesListValueChanged, true);
            app.selectAllInputFilesList.Text = 'select all';
            app.manualAnnotationsTypePanel = uibuttongroup(app.mainFigure);
            app.manualAnnotationsTypePanel.Title = 'Annotation Type';
            app.includeEventsCheckBox = uicheckbox(app.manualAnnotationsTypePanel);
            app.includeEventsCheckBox.Text = 'Events';
            app.includeEventsCheckBox.Value = true;
            app.includeRangesCheckBox = uicheckbox(app.manualAnnotationsTypePanel);
            app.includeRangesCheckBox.Text = 'Ranges';
            app.resetZoomButton = uibutton(app.mainFigure, 'push');
            app.resetZoomButton.ButtonPushedFcn = createCallbackFcn(app, @resetZoomButtonPushed, true);
            app.resetZoomButton.Text = 'Reset zoom';
            app.clearCacheButton = uibutton(app.mainFigure, 'push');
            app.clearCacheButton.ButtonPushedFcn = createCallbackFcn(app, @clearCacheButtonPushed, true);
            app.clearCacheButton.Text = 'Clear Cache';
            app.Statistics = uipanel(app.mainFigure);
            app.Statistics.Title = 'Statistics';
            app.statisticsTable = uitable(app.Statistics);
            app.statisticsTable.ColumnName = {'Class'; '#'; 'Avg Size'};
            app.statisticsTable.RowName = {};
            app.statisticsTable.ColumnEditable = false;
            app.eventDetectionPanel = uipanel(app.mainFigure);
            app.eventDetectionPanel.Title = '3-Event Detection (optional)';
            app.eventDetectionPanel.Visible = 'off';
            app.eventDetectionPreprocessingAlgorithmVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionPreprocessingAlgorithmVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectionPreprocessingAlgorithmVariablesTable.RowName = {};
            app.eventDetectionPreprocessingAlgorithmVariablesTable.ColumnEditable = [false true];
            app.eventDetectionPreprocessingAlgorithmsList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionPreprocessingAlgorithmsList.Items = {};
            app.eventDetectionPreprocessingAlgorithmsList.Value = {};
            app.eventDetectionPreprocessingSignalsList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionPreprocessingSignalsList.Items = {};
            app.eventDetectionPreprocessingSignalsList.Multiselect = 'on';
            app.eventDetectionPreprocessingSignalsList.Value = {};
            app.eventDetectionVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectionVariablesTable.RowName = {};
            app.eventDetectionVariablesTable.ColumnEditable = [false true];
            app.eventDetectionList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionList.Items = {};
            app.eventDetectionList.Value = {};
            app.annotationsPanel = uipanel(app.mainFigure);
            app.annotationsPanel.Title = '1b- Annotations';
            app.annotationMappingList = uilistbox(app.annotationsPanel);
            app.annotationMappingList.Items = {};
            app.annotationMappingList.Value = {};
            app.annotationMappingTable = uitable(app.annotationsPanel);
            app.annotationMappingTable.ColumnName = {'Source'; 'Target'};
            app.annotationMappingTable.RowName = {};
            app.annotationMappingTable.ColumnEditable = [false true];
            app.labelingPanel = uipanel(app.mainFigure);
            app.labelingPanel.Title = '5-Labeling';
            app.labelingPanel.Visible = 'off';
            app.labelingStrategiesList = uilistbox(app.labelingPanel);
            app.labelingStrategiesList.Items = {};
            app.labelingStrategiesList.Value = {};
            app.labelingVariablesTable = uitable(app.labelingPanel);
            app.labelingVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.labelingVariablesTable.RowName = {};
            app.labelingVariablesTable.ColumnEditable = [false true];
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end