classdef VisualizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        segmentsSourceButtonGroup    matlab.ui.container.ButtonGroup
        manualSegmentationRadio      matlab.ui.control.RadioButton
        automaticSegmentationRadio   matlab.ui.control.RadioButton
        annotationsPanel             matlab.ui.container.ButtonGroup
        includeEventsCheckBox        matlab.ui.control.CheckBox
        includeRangesCheckBox        matlab.ui.control.CheckBox
        LoadButton                   matlab.ui.control.Button
        segmentationPanel            matlab.ui.container.Panel
        segmentationVariablesTable   matlab.ui.control.Table
        segmentationList             matlab.ui.control.ListBox
        segmentationLabel            matlab.ui.control.Label
        groupingPanel                matlab.ui.container.Panel
        labelingStrategiesList       matlab.ui.control.ListBox
        groupsLabel                  matlab.ui.control.Label
        plotPanel                    matlab.ui.container.Panel
        groupButton                  matlab.ui.control.Button
        visualizationPanel           matlab.ui.container.Panel
        signalComputerVariablesTableVisualization  matlab.ui.control.Table
        signalComputersListVisualization  matlab.ui.control.ListBox
        signalsListVisualization     matlab.ui.control.ListBox
        visualizationClassesList     matlab.ui.control.ListBox
        sameScaleCheckbox            matlab.ui.control.CheckBox
        showLinesCheckbox            matlab.ui.control.CheckBox
        PlotStyleButtonGroup         matlab.ui.container.ButtonGroup
        overlappingPlotRadio         matlab.ui.control.RadioButton
        sequentialPlotRadio          matlab.ui.control.RadioButton
        visualizeButton              matlab.ui.control.Button
        plotToolbar                  matlab.ui.container.ButtonGroup
        zoomButton                   matlab.ui.control.ToggleButton
        panButton                    matlab.ui.control.ToggleButton
        eventDetectionPanel          matlab.ui.container.Panel
        eventDetectorVariablesTable  matlab.ui.control.Table
        eventDetectorList            matlab.ui.control.ListBox
        eventDetectionLabel          matlab.ui.control.Label
        preprocessingPanel           matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList     matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        %classes
        classesMap;
        annotations;
        
        %data loader
        segmentsLoader;
        segmentsLabeler;
        dataLoader;
        
        %data
        segments;
        groupedSegments;
        filteredSegments;
        columnNames;
        
        %preprocessing
        preprocessingConfigurator;
        
        %detection
        eventDetectorConfigurator;
        isManualMode;
        
        %segmentation
        segmentationConfigurator;
        currentSegmentsCreator;
        
        %labeling
        labelingConfigurator;
        
        %visualisation
        preprocessingConfiguratorVisualization;
        
        %ui plotting
        scrollPanel;
        segmentsPlotter;
        uiHandles;
    end
    
    methods (Access = private)
        
        %% UI
        function showLines = getShowLinesCheckbox(app)
            showLines = app.showLinesCheckbox.Value;
        end
        
        function includeEvents = getIncludeEvents(app)
            includeEvents = app.includeEventsCheckBox.Value;
        end
        
        function includeRanges = getIncludeRanges(app)
            includeRanges = app.includeRangesCheckBox.Value;
        end
        
        function resetGroupsLabel(app)
            app.groupsLabel.Text = "";
        end
        
        function resetSegmentsLabel(app)
            app.segmentationLabel.Text = "";
        end
        
        function updateGroupsLabel(app)
            str = sprintf('%d groups.',length(app.groupedSegments));
            app.groupsLabel.Text = str;
        end
        
        function updateSegmentsLabel(app)
            str = sprintf('%d files.',length(app.segments));
            app.segmentationLabel.Text = str;
        end

        function value = getSameScale(app)
            value = app.sameScaleCheckbox.Value;
        end

        function updateGroupsList(app)
            labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
            classesStr = cell(1,length(app.groupedSegments));
            
            for i = 1 : length(app.groupedSegments)
                nSegments = length(app.groupedSegments{i});
                className = labelingStrategy.classNames{i};
                str = sprintf('%s: %d',className,nSegments);
                classesStr{i} = str;
            end
            app.visualizationClassesList.Items = classesStr;
            app.visualizationClassesList.Value = app.visualizationClassesList.Items;
        end

        function updateEventDetectionTablesVisibility(app)
            if app.isManualMode
                app.preprocessingPanel.Visible = 'Off';
                app.eventDetectionPanel.Visible = 'Off';
                app.annotationsPanel.Visible = 'On';
                
            else
                app.preprocessingPanel.Visible = 'On';
                app.eventDetectionPanel.Visible = 'On';
                app.annotationsPanel.Visible = 'Off';
            end
        end
        
        function idxs = getSelectedClassIdxs(app)
            idxStr = app.visualizationClassesList.Value;
            [~,idxs] = ismember(idxStr,app.visualizationClassesList.Items);
        end
        
        
        %% methods
        function loadUIConfigurators(app)
            
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            app.preprocessingConfigurator.setDefaultColumnNames();
            
            app.preprocessingConfiguratorVisualization = PreprocessingConfigurator(...
                app.signalsListVisualization,...
                app.signalComputersListVisualization,...
                app.signalComputerVariablesTableVisualization);
            
            app.preprocessingConfiguratorVisualization.setDefaultColumnNames();
            app.signalsListVisualization.Value = app.signalsListVisualization.Items{1};
            
            
            eventDetectors = {SignalComputer.NoOpComputer(), SimplePeakDetector, MatlabPeakDetector};
            app.eventDetectorConfigurator = EventDetectorConfigurator(...
                eventDetectors,...
                app.eventDetectorList,...
                app.eventDetectorVariablesTable);

            segmentationStrategies = {ManualSegmentation};
            
            app.segmentationConfigurator = SegmentationConfigurator(...
                segmentationStrategies,...
                app.segmentationList...
                ,app.segmentationVariablesTable);
            
            
            labelingStrategies = app.dataLoader.loadAllLabelingStrategies();
            
            app.labelingConfigurator = LabelingConfigurator(...
                labelingStrategies,app.labelingStrategiesList);
        end
        
        function plotData(app)
            selectedClassesIdxs = app.getSelectedClassIdxs();
            labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
            groupNames = labelingStrategy.classNames(selectedClassesIdxs);
            app.segmentsPlotter.sameScale = app.getSameScale();
            
            app.segmentsPlotter.sequentialSegments = app.sequentialPlotRadio.Value;
            
            app.segmentsPlotter.plotSegments(app.filteredSegments,groupNames);
        end
        
        function applySignalComputers(app)
            
            filterComputer = app.preprocessingConfiguratorVisualization.createSignalComputerWithUIParameters();
            selectedClassesIdxs = app.getSelectedClassIdxs();
            
            nSelectedClasses = length(selectedClassesIdxs);
            
            app.filteredSegments = cell(1,nSelectedClasses);
            
            for i = 1 : nSelectedClasses
                currentClassIdx = selectedClassesIdxs(i);
                segmentsCurrentGroup = app.groupedSegments{currentClassIdx};
                segmentsArray = repmat(Segment(),1,length(segmentsCurrentGroup));
                
                for j = 1 : length(segmentsArray)
                    segment = segmentsCurrentGroup(j);
                    filteredData = filterComputer.compute(segment.window);
                    filteredSegment = Segment(segment.file,filteredData,segment.class,segment.eventIdx);
                    segmentsArray(j) = filteredSegment;
                end
                app.filteredSegments{i} = segmentsArray;
            end
        end
        
        function updateCurrentSegmentsCreator(app)
            
            app.currentSegmentsCreator.preprocessedSignalsLoader = PreprocessedSignalsLoader();
            segmentationAlgorithm = app.segmentationConfigurator.createSegmentationStrategyWithUIParameters();
            
            if app.isManualMode
                segmentationAlgorithm.includeEvents = app.getIncludeEvents();
                segmentationAlgorithm.includeRanges = app.getIncludeRanges();
                
                segmentationAlgorithm.manualAnnotations = app.annotations;
                app.segmentsLoader.segmentsLabeler = [];
            else
                eventDetector = app.eventDetectorConfigurator.createEventDetectorWithUIParameters();
                segmentationAlgorithm.eventDetector = eventDetector;
                app.currentSegmentsCreator.preprocessedSignalsLoader.preprocessor = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
                
                app.segmentsLabeler.labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
                app.segmentsLoader.segmentsLabeler = app.segmentsLabeler;
            end
            
            app.currentSegmentsCreator.segmentationAlgorithm = segmentationAlgorithm;
        end
        
        function loadSegments(app)
            app.segments = app.segmentsLoader.loadOrCreateSegments();
        end
        
        function validData = checkValidComputedSegments(app)
            validData = true;
            for i = 1 : length(app.filteredSegments)
                segmentsCurrentPlayer = app.filteredSegments{i};
                for j = 1 : length(segmentsCurrentPlayer)
                    segment = segmentsCurrentPlayer(j);
                    if isempty(segment) || isempty(segment.window)
                        validData = false;
                        break;
                    end
                end
                if validData == false
                    break;
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clear ClassesMap;
            app.classesMap = ClassesMap.instance();
            app.dataLoader = DataLoader();
            
            app.annotations = app.dataLoader.loadAllAnnotations();
            
            app.segmentsLoader = SegmentsLoader();
            app.currentSegmentsCreator = SegmentsCreator();
            app.segmentsLoader.segmentsCreator = app.currentSegmentsCreator;
            
            app.segmentsLabeler = SegmentsLabeler();
            app.segmentsLabeler.manualAnnotations = app.annotations;
            
            app.isManualMode = true;
            
            app.loadUIConfigurators();
            
            app.segmentsPlotter = PreprocessingSegmentsPlotter(app.plotPanel);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if(~app.isManualMode || app.getIncludeEvents() || app.getIncludeRanges())
                app.resetGroupsLabel();
                app.groupedSegments = [];
                app.updateCurrentSegmentsCreator();
                app.loadSegments();
                app.updateSegmentsLabel();
            end
        end

        % Button pushed function: groupButton
        function groupButtonPushed(app, event)
            app.resetGroupsLabel();
            if ~isempty(app.segments)
                segmentsGrouper = SegmentsGrouper();
                labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
                app.groupedSegments = segmentsGrouper.groupSegments(app.segments,labelingStrategy);
                app.updateGroupsList();
                app.updateGroupsLabel();
            end
        end

        % Button pushed function: visualizeButton
        function visualizeButtonPushed(app, event)

            if ~isempty(app.groupedSegments)
                app.applySignalComputers();
                validData = app.checkValidComputedSegments();
                if validData
                    app.plotData();
                else
                    fprintf('SignalExplorerApp - %s\n',Constants.kInvalidFilterComputedError);
                end
            end
        end

        % Selection changed function: plotToolbar
        function handleToolbarButtonChanged2(app, event)
            selectedButton = app.plotToolbar.SelectedObject;
            if(selectedButton == app.zoomButton)
                app.segmentsPlotter.setZoom('on');
            else
                app.segmentsPlotter.setPan('on');
            end
        end

        % Selection changed function: segmentsSourceButtonGroup
        function segmentsSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.segmentsSourceButtonGroup.SelectedObject;
            if(selectedButton == app.manualSegmentationRadio)
        
                app.isManualMode = true;
                app.updateEventDetectionTablesVisibility();
                
                app.segmentationConfigurator.segmentationStrategies = {ManualSegmentation};
                app.segmentationConfigurator.reloadUI();
            else
                app.isManualMode = false;
                app.updateEventDetectionTablesVisibility();
                
                eventDetector = app.eventDetectorConfigurator.createEventDetectorWithUIParameters();
                segmentationStrategy = EventSegmentation(eventDetector);
                app.segmentationConfigurator.segmentationStrategies = {segmentationStrategy};
                app.segmentationConfigurator.reloadUI();
            end
        end

        % Value changed function: sameScaleCheckbox
        function sameScaleCheckboxValueChanged(app, event)
            sameScale = app.sameScaleCheckbox.Value;
            if(~isempty(app.segmentsPlotter))
                app.segmentsPlotter.setSameScale(sameScale);
            end
        end

        % Value changed function: showLinesCheckbox
        function showLinesCheckboxValueChanged(app, event)
            showVerticalLines = app.showLinesCheckbox.Value;
            
            if(~isempty(app.segmentsPlotter))
                app.segmentsPlotter.setShowLines(showVerticalLines);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.segmentsSourceButtonGroup = uibuttongroup(app.UIFigure);
            app.segmentsSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @segmentsSourceButtonGroupSelectionChanged, true);
            app.segmentsSourceButtonGroup.Title = 'Segments Source';
            app.manualSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.manualSegmentationRadio.Text = 'Manual annotations';
            app.manualSegmentationRadio.Value = true;
            app.automaticSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.automaticSegmentationRadio.Text = 'Automatic segmentation';
            app.annotationsPanel = uibuttongroup(app.UIFigure);
            app.annotationsPanel.Title = 'Annotations';
            app.includeEventsCheckBox = uicheckbox(app.annotationsPanel);
            app.includeEventsCheckBox.Text = 'Events';
            app.includeEventsCheckBox.Value = true;
            app.includeRangesCheckBox = uicheckbox(app.annotationsPanel);
            app.includeRangesCheckBox.Text = 'Ranges';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.segmentationPanel = uipanel(app.UIFigure);
            app.segmentationPanel.Title = 'Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true];
            app.segmentationList = uilistbox(app.segmentationPanel);
            app.segmentationList.Items = {};
            app.segmentationList.Value = {};
            app.segmentationLabel = uilabel(app.segmentationPanel);
            app.segmentationLabel.Text = '';
            app.groupingPanel = uipanel(app.UIFigure);
            app.groupingPanel.Title = 'Grouping';
            app.labelingStrategiesList = uilistbox(app.groupingPanel);
            app.labelingStrategiesList.Items = {};
            app.labelingStrategiesList.Value = {};
            app.groupsLabel = uilabel(app.groupingPanel);
            app.groupsLabel.Text = '';
            app.plotPanel = uipanel(app.UIFigure);
            app.groupButton = uibutton(app.UIFigure, 'push');
            app.groupButton.ButtonPushedFcn = createCallbackFcn(app, @groupButtonPushed, true);
            app.groupButton.Text = 'Group';
            app.visualizationPanel = uipanel(app.UIFigure);
            app.visualizationPanel.Title = 'Visualization';
            app.signalComputerVariablesTableVisualization = uitable(app.visualizationPanel);
            app.signalComputerVariablesTableVisualization.ColumnName = {'Variable'; 'Value'};
            app.signalComputerVariablesTableVisualization.RowName = {};
            app.signalComputerVariablesTableVisualization.ColumnEditable = [false true];
            app.signalComputersListVisualization = uilistbox(app.visualizationPanel);
            app.signalComputersListVisualization.Items = {};
            app.signalComputersListVisualization.Value = {};
            app.signalsListVisualization = uilistbox(app.visualizationPanel);
            app.signalsListVisualization.Items = {};
            app.signalsListVisualization.Multiselect = 'on';
            app.signalsListVisualization.Value = {};
            app.visualizationClassesList = uilistbox(app.visualizationPanel);
            app.visualizationClassesList.Items = {};
            app.visualizationClassesList.Multiselect = 'on';
            app.visualizationClassesList.Value = {};
            app.sameScaleCheckbox = uicheckbox(app.visualizationPanel);
            app.sameScaleCheckbox.ValueChangedFcn = createCallbackFcn(app, @sameScaleCheckboxValueChanged, true);
            app.sameScaleCheckbox.Text = 'Same scale';
            app.sameScaleCheckbox.Value = true;
            app.showLinesCheckbox = uicheckbox(app.visualizationPanel);
            app.showLinesCheckbox.ValueChangedFcn = createCallbackFcn(app, @showLinesCheckboxValueChanged, true);
            app.showLinesCheckbox.Text = 'Show lines';
            app.showLinesCheckbox.Value = true;
            app.PlotStyleButtonGroup = uibuttongroup(app.visualizationPanel);
            app.PlotStyleButtonGroup.Title = 'Plot Style';
            app.overlappingPlotRadio = uiradiobutton(app.PlotStyleButtonGroup);
            app.overlappingPlotRadio.Text = 'Overlapping';
            app.overlappingPlotRadio.Value = true;
            app.sequentialPlotRadio = uiradiobutton(app.PlotStyleButtonGroup);
            app.sequentialPlotRadio.Text = 'Sequential';
            app.visualizeButton = uibutton(app.UIFigure, 'push');
            app.visualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualizeButton.Text = 'Visualize';
            app.plotToolbar = uibuttongroup(app.UIFigure);
            app.plotToolbar.SelectionChangedFcn = createCallbackFcn(app, @handleToolbarButtonChanged2, true);
            app.zoomButton = uitogglebutton(app.plotToolbar);
            app.zoomButton.Text = 'Zoom';
            app.panButton = uitogglebutton(app.plotToolbar);
            app.panButton.Text = 'Pan';
            app.panButton.Value = true;
            app.eventDetectionPanel = uipanel(app.UIFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectionPanel.Visible = 'off';
            app.eventDetectorVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectorVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectorVariablesTable.RowName = {};
            app.eventDetectorVariablesTable.ColumnEditable = [false true];
            app.eventDetectorList = uilistbox(app.eventDetectionPanel);
            app.eventDetectorList.Items = {};
            app.eventDetectorList.Value = {};
            app.eventDetectionLabel = uilabel(app.eventDetectionPanel);
            app.eventDetectionLabel.Text = '';
            app.preprocessingPanel = uipanel(app.UIFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingPanel.Visible = 'off';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = VisualizationApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end