classdef EvaluationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EvaluationAppUIFigure           matlab.ui.Figure
        segmentationPanel               matlab.ui.container.Panel
        segmentationVariablesTable      matlab.ui.control.Table
        segmentationList                matlab.ui.control.ListBox
        eventDetectionPanel             matlab.ui.container.Panel
        eventDetectorVariablesTable     matlab.ui.control.Table
        eventDetectorList               matlab.ui.control.ListBox
        eventDetectionLabel             matlab.ui.control.Label
        preprocessingPanel              matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList        matlab.ui.control.ListBox
        segmentsSourceButtonGroup       matlab.ui.container.ButtonGroup
        manualSegmentationRadio         matlab.ui.control.RadioButton
        automaticSegmentationRadio      matlab.ui.control.RadioButton
        validationPanel                 matlab.ui.container.Panel
        testList                        matlab.ui.control.ListBox
        trainList                       matlab.ui.control.ListBox
        moveFileRightButton             matlab.ui.control.Button
        moveFileLeftButton              matlab.ui.control.Button
        selectAllFilesTrainingCheckBox  matlab.ui.control.CheckBox
        selectAllFilesTestingCheckBox   matlab.ui.control.CheckBox
        validationButtonGroup           matlab.ui.container.ButtonGroup
        holdoutRadio                    matlab.ui.control.RadioButton
        crossValidationRadio            matlab.ui.control.RadioButton
        groupingPanel                   matlab.ui.container.Panel
        labelGroupingsList              matlab.ui.control.ListBox
        includeLabelsTable              matlab.ui.control.Table
        featureExtractionPanel          matlab.ui.container.Panel
        shouldNormalizeFeaturesCheckBox  matlab.ui.control.CheckBox
        featuresSourceButtonGroup       matlab.ui.container.ButtonGroup
        featuresFileRadio               matlab.ui.control.RadioButton
        featuresManuallyRadio           matlab.ui.control.RadioButton
        featureExtractionFileList       matlab.ui.control.ListBox
        manualFeatureExtractionPanel    matlab.ui.container.Panel
        featuresList                    matlab.ui.control.ListBox
        selectedFeaturesList            matlab.ui.control.ListBox
        addFeatureButton                matlab.ui.control.Button
        removeFeatureButton             matlab.ui.control.Button
        StartEditFieldLabel             matlab.ui.control.Label
        featureStartRangeEditText       matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        featureEndRangeEditText         matlab.ui.control.NumericEditField
        featureFullSegmentCheckBox      matlab.ui.control.CheckBox
        AxesLabel                       matlab.ui.control.Label
        featureAxisEditText             matlab.ui.control.EditField
        saveFeatures                    matlab.ui.control.Button
        confusionMatrixAxes             matlab.ui.control.UIAxes
        regroupingPanel                 matlab.ui.container.Panel
        regroupingLabelingStrategyList  matlab.ui.control.ListBox
        regroupingTextBox               matlab.ui.control.TextArea
        regroupButton                   matlab.ui.control.Button
        exportPanel                     matlab.ui.container.Panel
        exportNormalisationValuesCheckBox  matlab.ui.control.CheckBox
        exportFormatGroup               matlab.ui.container.ButtonGroup
        exportMatlabFormatCheckBox      matlab.ui.control.RadioButton
        exportTextFormatCheckBox        matlab.ui.control.RadioButton
        exportLabelTypeGroup            matlab.ui.container.ButtonGroup
        exportTextLabelsRadio           matlab.ui.control.RadioButton
        exportNumberLabelsRadio         matlab.ui.control.RadioButton
        exportButton                    matlab.ui.control.Button
        exportTablesList                matlab.ui.control.ListBox
        annotationsPanel                matlab.ui.container.ButtonGroup
        includeEventsCheckBox           matlab.ui.control.CheckBox
        includeRangesCheckBox           matlab.ui.control.CheckBox
        includeLabelsPanel              matlab.ui.container.Panel
        classifiersList                 matlab.ui.control.ListBox
        classifiersVariablesTable       matlab.ui.control.Table
        classifyButton                  matlab.ui.control.Button
        featureSelectionPanel           matlab.ui.container.Panel
        findFeatures                    matlab.ui.control.Button
        printFeatures                   matlab.ui.control.Button
        selectFeaturesButton            matlab.ui.control.Button
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        nFeaturesEditField              matlab.ui.control.NumericEditField
        featuresTextBox                 matlab.ui.control.TextArea
        createButton                    matlab.ui.control.Button
        filesPanel                      matlab.ui.container.Panel
        inputFilesList                  matlab.ui.control.ListBox
        selectAllInputFilesList         matlab.ui.control.CheckBox
        clearCacheButton                matlab.ui.control.Button
        metricsPanel                    matlab.ui.container.Panel
        metricsTable                    matlab.ui.control.Table
        visualizeARCButton              matlab.ui.control.Button
        labelingPanel                   matlab.ui.container.Panel
        labelingStrategiesList          matlab.ui.control.ListBox
        labelingVariablesTable          matlab.ui.control.Table
        executeChainFile                matlab.ui.control.Button
        chainFilesList                  matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        
        % data
        dataLoader;
        fileNames;
        filesMap;
        annotations;
        fileLoader;
        loadedARChainFiles;
        
        %tables
        featureTables;
        generatedTableNames;
        
        %arc
        arcChain;
        
        %configurators
        preprocessingConfigurator;
        eventDetectorConfigurator;
        segmentationConfigurator;
        labelingConfigurator;
        groupingConfigurator;
        groupingConfiguratorRegrouping;
        featureExtractionConfigurator;
        classificationConfigurator;
        
        %helpers
        featureSelector;
        featureNormalizer;
        tableExporter;
        confusionMatrixPlotter;
        manualSegmentation;
        defaultLabelGrouping;
        
        %state
        groupedWithDefaultLabelGrouping;
        isManualMode;
        
        %results
        confusionMatrix;
        generatedFeatureNames;
    end
    
    methods (Access = private)
        
        function loadConfigurators(app)
            
            signalComputers = Palette.PreprocessingComputers();
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                signalComputers,...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            app.preprocessingConfigurator.setDefaultColumnNames();
            
            eventDetectors = Palette.EventDetectionComputers();
            
            app.eventDetectorConfigurator = ComputerConfigurator(...
                eventDetectors,...
                app.eventDetectorList...
                ,app.eventDetectorVariablesTable);
            
            segmentationStrategies = {app.manualSegmentation};
            
            app.segmentationConfigurator = ComputerConfigurator(...
                segmentationStrategies,...
                app.segmentationList,...
                app.segmentationVariablesTable);
            
            app.segmentationConfigurator = ComputerConfigurator(...
                segmentationStrategies,...
                app.segmentationList,...
                app.segmentationVariablesTable);
            
            app.labelingConfigurator = ComputerConfigurator(...
                Palette.LabelingStrategyComputers(),...
                app.labelingStrategiesList,...
                app.labelingVariablesTable);
            
            labelGroupings = app.dataLoader.loadAllLabelGroupings();
            app.defaultLabelGrouping = labelGroupings(1);
            
            app.groupingConfigurator = GroupingConfigurator(...
                labelGroupings,app.labelGroupingsList,app.includeLabelsTable);
            
            labelGroupingsRegrouping = labelGroupings(2:end);
            
            app.groupingConfiguratorRegrouping = GroupingConfigurator(...
                labelGroupingsRegrouping, app.regroupingLabelingStrategyList);
            
            defaultFeatureExtractors = FeatureExtractor.CreateDefaultFeatureExtractors();
            
            app.loadedARChainFiles = Helper.listARChainFiles();
            
            app.featureExtractionConfigurator = FeatureExtractionConfigurator(...
                defaultFeatureExtractors,...
                app.loadedARChainFiles,...
                app.featuresList,...
                app.addFeatureButton,...
                app.removeFeatureButton,...
                app.selectedFeaturesList,...
                app.featureStartRangeEditText,...
                app.featureEndRangeEditText,...
                app.featureFullSegmentCheckBox,...
                app.featureAxisEditText,...
                app.featureExtractionFileList,...
                app.featuresSourceButtonGroup,...
                app.featuresFileRadio,...
                app.featuresManuallyRadio,...
                app.manualFeatureExtractionPanel);
            
            classificationAlgorithms = Palette.ClassificationComputers();           
            
            app.classificationConfigurator = ComputerConfigurator(...
                classificationAlgorithms,...
                app.classifiersList...
                ,app.classifiersVariablesTable);
            
            app.updateEventDetectionTablesVisibility();
        end
        
        function loadFilesMap(app)
            for i = 1 : length(app.fileNames)
                fileName = app.fileNames{i};
                app.filesMap(fileName) = i;
            end
        end
    
        function populateFilesList(app)
            app.inputFilesList.Items = app.fileNames(1:end);
            app.inputFilesList.Value = app.inputFilesList.Items;
        end
    
        function populateChainFilesList(app)
            app.chainFilesList.Items = app.loadedARChainFiles;
        end
        
        function updateValidationTablesLists(app)
            
            app.trainList.Items = app.generatedTableNames';
            app.trainList.Value = app.trainList.Items;
            app.testList.Items = {};
        end
        
        function selectedFilesStr = popSelectedFilesFromList(~,list)
            selectedFilesStr = list.Value;
            [~,selectedFileIdxs] = ismember(selectedFilesStr,list.Items);
            listIndices = setdiff(1:length(list.Items),selectedFileIdxs);
            list.Items = list.Items(listIndices);
        end
        
        function list = pushFilesToList(~,files,list)
            nItems = length(list.Items);
            nFiles = length(files);
            list.Items(nItems + 1:nItems + nFiles) = files;
        end
        
        function table = createTableWithFilesInList(app,list)
            selectedFileStr = list.Value;
            [~,selectedFileIdxs] = ismember(selectedFileStr,list.Items);
            
            table = [];
            if ~isempty(selectedFileIdxs)
                fileIdxs = app.getGlobalFileIdxs(list,selectedFileIdxs);
                table = app.tableSet.mergedTableForIndices(fileIdxs);
            end
        end
        
        function fileIdxs = getGlobalFileIdxs(app,list,inFileIdxs)
            nFileIdxs = length(inFileIdxs);
            fileIdxs = zeros(1,nFileIdxs);
            for i = 1 : nFileIdxs
                fileIdx = inFileIdxs(i);
                fileName = list.Items{fileIdx};
                fileIdx = app.filesMap(fileName);
                fileIdxs(i) = fileIdx;
            end
        end
        
        function printTable(app,table)
            if ~isempty(table)
                printer = StatisticsPrinter();
                printer.printTableStatistics(table, app.defaultLabelGrouping, 0);
            end
        end
        
        function printGroupedTable(app,table)
            if ~isempty(table)
                printer = StatisticsPrinter();
                labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
                printer.printTableStatistics(table, labelGrouping, 0);
            end
        end
        
        function [trainTable, testTable] = normalizeTables(app,trainTable,testTable)
            if app.shouldNormalizeFeaturesCheckBox.Value == 1
                app.featureNormalizer.fit(trainTable);
                trainTable = app.featureNormalizer.normalize(trainTable);
                testTable = app.featureNormalizer.normalize(testTable);
            end
        end
        
        function groupedConfusionMatrix = groupConfusionMatrix(app,labelGrouping)
            numClasses = labelGrouping.numClasses;
            
            classesMap = ClassesMap.instance();
            if app.confusionMatrix.nClasses > classesMap.numClasses
                numClasses = numClasses + 1;
            end
            
            groupedConfusionMatrixData = zeros(numClasses,numClasses);
            confusionMatrixData = app.confusionMatrix.data;
            for i = 1 : app.confusionMatrix.nClasses
                row = labelGrouping.labelForClass(i);
                if row > numClasses
                    row = numClasses;
                end
                for j = 1 : app.confusionMatrix.nClasses
                    col = labelGrouping.labelForClass(j);
                    if col > numClasses
                        col = numClasses;
                    end
                    groupedConfusionMatrixData(row,col) = groupedConfusionMatrixData(row,col) + confusionMatrixData(i,j);
                end
            end
            groupedConfusionMatrix = ConfusionMatrix(groupedConfusionMatrixData);
            groupedConfusionMatrix.containsNullClass = app.confusionMatrix.containsNullClass;
        end
        
        function plotConfusionMatrix(app,confusionMatrix,classNames)
            app.confusionMatrixAxes.Visible = true;
            app.confusionMatrixPlotter.plotConfusionMatrix(confusionMatrix,classNames);
        end
        
        function exportTable(app,table,fileName)
            if ~isempty(table)
                
                previousLabels = table.label;
                
                if app.exportTextLabelsRadio.Value == 1
                    labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
                    
                    includeLabelIdxs = app.getIncludeLabelIdxs();
                    
                    classNames = [labelGrouping.classNames(includeLabelIdxs), Constants.kNullClassGroupStr];
                    table.label (table.label  == 0) = length(classNames);                   
                    table.label = classNames(table.label)';
                else
                    table.label = table.label;
                end
                
                if app.exportMatlabFormatCheckBox.Value == 1
                    t = table.table;
                    save(fileName,'t');
                else
                    app.tableExporter.exportTable(table.table,fileName);
                end
                
                table.label = previousLabels;
            end
        end
        
        function exportNormalisationValues(app)
            if ~isempty(app.featureNormalizer.means)
                means = app.featureNormalizer.means;
                stds = app.featureNormalizer.stds;
                normalisationValues = array2table([means;stds]);
                normalisationValues.Properties.VariableNames = app.generatedFeatureNames(1:end-1);
                normalisationValues.Properties.RowNames = {'means','deviations'};
                app.tableExporter.writeRowNames = true;
                app.tableExporter.exportTable(normalisationValues,Constants.kNormalisationFileName);
                app.tableExporter.writeRowNames = false;
            end
        end
        
        %% UI
        function selectedIdxs = getSelectedIdxsForList(~, uiList)
            selectedStrs = uiList.Value;
            [~,selectedIdxs] = ismember(selectedStrs,uiList.Items);
        end
        
        function updateEventDetectionTablesVisibility(app)
            if app.isManualMode
                app.annotationsPanel.Visible = 'On';
                app.eventDetectionPanel.Visible = 'Off';
                app.preprocessingPanel.Visible = 'Off';
                app.preprocessingSignalComputerList.Visible = 'Off';
            else
                app.annotationsPanel.Visible = 'Off';
                app.eventDetectionPanel.Visible = 'On';
                app.preprocessingPanel.Visible = 'On';
                app.preprocessingSignalComputerList.Visible = 'On';
            end
        end
        
        function updateLabelingPanelVisibility(app)
            if app.isManualMode
                app.labelingPanel.Visible = 'Off';
            else
                app.labelingPanel.Visible = 'On';
            end
        end
        
        function idx = getSelectedSegmentLoaderIdx(app)
            switch app.segmentationGroup.Selectedappect
                case app.manualSegmentationRadio
                    idx = 1;
                case app.simplifiedSegmentationRadio
                    idx = 2;
            end
        end
        
        function idx = getSelectedRegroupingLabelingIdx(app)
            idx = -1;
            switch app.regroupGroup.Selectedappect
                case app.regroupingGoodBadLabelingRadio
                    idx = 2;
                case app.regroupingGroupedLabelingRadio
                    idx = 3;
            end
        end
        
        function nFeatures = getNFeatures(app)
            nFeatures = app.nFeaturesEditField.Value;
        end
        
        function includeEvents = getIncludeEvents(app)
            includeEvents = app.includeEventsCheckBox.Value;
        end
        
        function includeRanges = getIncludeRanges(app)
            includeRanges = app.includeRangesCheckBox.Value;
        end
        
        function labelIdxsBoolean = getIncludeLabelIdxsBoolean(app)
            labelIdxsBoolean = app.groupingConfigurator.getSelectedLabels();
        end
        
        function labelIdxs = getIncludeLabelIdxs(app)
            labelIdxsBoolean = app.getIncludeLabelIdxsBoolean();
            labelIdxs = 1:length(labelIdxsBoolean);
            labelIdxs = labelIdxs(labelIdxsBoolean);
        end
        
        function labelStr = labelForTable(~,table,tableTypeStr,labelGrouping)
            
            if isempty(table)
                labelStr = sprintf('%s table:\n empty',tableTypeStr);
            else
                labelStr = sprintf('%s table:\n %dx%d\n (%s)',tableTypeStr,table.height,...
                    table.width,labelGrouping.name);
            end
        end
        
        function loadIncludeLabelsList(app,labelGrouping)
            app.includeLabelsList.Items = labelGrouping.classNames;
            app.includeLabelsList.Value = app.includeLabelsList.Items;
        end
        
        function selectAllFilesInList(~,list)
            list.Value = list.Items;
        end
        
        function buildChainFromUI(app)
            
            segmentationAlgorithm = app.segmentationConfigurator.createComputerWithUIParameters();
            
            app.fileLoader = FileLoader();
            labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
            mapper = LabelMapper(labelGrouping);
            featureExtractor = app.featureExtractionConfigurator.createFeatureExtractorWithUIParameters();    
            tableRowSelector = TableRowSelector(app.getIncludeLabelIdxsBoolean());
            
            if app.isManualMode
                segmentationAlgorithm.includeEvents = app.getIncludeEvents();
                segmentationAlgorithm.includeRanges = app.getIncludeRanges();
                
                app.arcChain = Computer.ComputerWithSequence({app.fileLoader,segmentationAlgorithm,mapper,featureExtractor,tableRowSelector});
                
            else
                preprocessor = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
                eventDetector = app.eventDetectorConfigurator.createComputerWithUIParameters();
                labeler = app.labelingConfigurator.createComputerWithUIParameters();
                
                app.arcChain = Computer.ComputerWithSequence({app.fileLoader,PropertyGetter('data'),...
                    preprocessor,eventDetector,segmentationAlgorithm,labeler,mapper,...
                    featureExtractor,tableRowSelector});
            end
        end
        
        function [tableSet,metricSum] = executeChain(app,uiList)
            selectedFileIdxs = app.getSelectedIdxsForList(uiList);
            selectedFileNames = uiList.Items(selectedFileIdxs);
                        
            nFiles = length(selectedFileNames);
                        
            tables = repmat(Table,1,nFiles);
            
            metricSum = Metric();
            
            waitBar = waitbar(0,'Executing Chain');
            
            for i = 1 : nFiles
                selectedFileIdx = selectedFileIdxs(i);
                app.fileLoader.fileName = selectedFileNames{i};
                file = app.dataLoader.loadDataFile(selectedFileNames{i});
                
                waitBarMsg = sprintf('Executing Chain for file %s ...',app.fileLoader.fileName);
                waitbar(i/nFiles,waitBar,waitBarMsg);
                
                annotation = app.annotations(selectedFileIdx);
                Computer.SetSharedContextVariable(Constants.kSharedVariableCurrentDataFile, file);
                Computer.SetSharedContextVariable(Constants.kSharedVariableCurrentAnnotationFile, annotation);
                
                [tables(i),metrics] = Computer.ExecuteChain(app.arcChain,'start',true);
                metricSum.addMetric(metrics);
                
            end
            
            close(waitBar);
            tableSet = TableSet(tables);
        end
        
        
        function b = isHoldOutValidation(app)
            b = app.holdoutRadio.Value;
        end
        
        function [predictedLabels, truthLabels] = holdOutClassification(app,classifier)
            
            trainTable = app.getGeneratedTableWithNames(app.trainList.Value);
            testTable = app.getGeneratedTableWithNames(app.testList.Value);
            
            validFeatureIdxs = FeatureNormalizer.ComputeValidFeaturesForTable(trainTable);
            trainTable.filterTableToColumns([validFeatureIdxs true]);
            testTable.filterTableToColumns([validFeatureIdxs true]);
            
            app.generatedFeatureNames = trainTable.columnNames;
            
            app.normalizeTables(trainTable,testTable);
            
            holdOutValidator = HoldOutValidator();
            holdOutValidator.testTable = testTable;
            holdOutValidator.trainTable = trainTable;
            holdOutValidator.classifier = classifier;
            
            predictedLabels = holdOutValidator.validate();
            truthLabels = holdOutValidator.getTruthLabels();
        end
        
        function [predictedLabels, truthLabels] = leaveOneSubjectOutClassification(app,classifier)
            
            crossValidator = LeaveOneOutCrossValidator();
            crossValidator.classifier = classifier;
            crossValidator.tableSet = app.featureTables;
            crossValidator.shouldNormalizeFeatures = app.shouldNormalizeFeaturesCheckBox.Value;

            predictedLabels = crossValidator.validate();
            truthLabels = crossValidator.getTruthLabels();
        end
        
        function result = checkCreateTablesConfiguration(app)
            result = true;
            if isempty(app.inputFilesList.Value)
                result = false;
            end
        end
        
        function result = checkClassificationConfiguration(app)
            result = true;
            if(isempty(app.trainList.Value) || (app.isHoldOutValidation() && isempty(app.testList.Value)))
                result = false;
            end
        end
        
        function updateExportFeatureTablesList(app)
            
            tablesStr = cell(1,app.featureTables.nTables);
            for i = 1 : app.featureTables.nTables
                table = app.featureTables.tables(i);
                fileName = app.generatedTableNames{i};
                tablesStr{i} = sprintf('%s %dx%d',fileName,table.height,table.width);
            end
                        
            app.exportTablesList.Items = tablesStr;
        end
        
        function idx = idxsOfGeneratedTableWithNames(app,tableName)
            [~,idx] = ismember(tableName,app.generatedTableNames);
        end
        
        function tables = getGeneratedTableWithNames(app,tableNames)
            trainIdxs = app.idxsOfGeneratedTableWithNames(tableNames);
            tables = app.featureTables.mergedTableForIndices(trainIdxs);
        end
        
        function updateSelectedFeatures(app)
            app.featuresTextBox.Value = Helper.arrayToString(app.featureSelector.selectedFeatureIdxs,', ');
            app.nFeaturesEditField.Value = length(app.featureSelector.selectedFeatureIdxs);
        end
        
        function updateMetricsTable(app,metrics)
            
            cellArray = cell(1,4);
            cellArray{1} = 'Algorithm';
            cellArray{2} = metrics.flops;
            cellArray{3} = metrics.memory;
            cellArray{4} = metrics.outputSize;
            
            if isempty(app.metricsTable.Data)
                app.metricsTable.Data = cellArray;
            else
                app.metricsTable.Data(end+1,:) = cellArray;
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear ClassesMap;
            clear Cache;
            
            app.isManualMode = true;
            app.dataLoader = DataLoader();
            app.annotations = app.dataLoader.loadAllAnnotations();
            app.manualSegmentation = ManualSegmentation(app.annotations);
            app.fileNames = Helper.listDataFiles();
            
            app.featureNormalizer = FeatureNormalizer();
            app.featureSelector = FeatureSelector();
            app.updateSelectedFeatures();
            
            app.tableExporter = TableExporter();
            app.filesMap = containers.Map();
            
            app.confusionMatrixPlotter = ConfusionMatrixPlotter(app.confusionMatrixAxes);
            
            app.loadFilesMap();
            
            app.loadConfigurators();
            app.populateFilesList();
            app.populateChainFilesList();
            app.selectAllFilesInList(app.trainList);
            app.selectAllFilesInList(app.testList);
        end

        % Button pushed function: moveFileRightButton
        function moveFileRightButtonPushed(app, event)
            
            selectedFilesStr = app.popSelectedFilesFromList(app.trainList);
            app.pushFilesToList(selectedFilesStr,app.testList);
        end

        % Button pushed function: moveFileLeftButton
        function moveFileLeftButtonPushed(app, event)
            
            selectedFileStr = app.popSelectedFilesFromList(app.testList);
            app.pushFilesToList(selectedFileStr,app.trainList);
        end

        % Selection changed function: segmentsSourceButtonGroup
        function segmentsSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.segmentsSourceButtonGroup.SelectedObject;
            if(selectedButton == app.manualSegmentationRadio)
                
                app.isManualMode = true;
                
                app.segmentationConfigurator.computers = {app.manualSegmentation};
                app.segmentationConfigurator.reloadUI();
            else
                app.isManualMode = false;
                
                eventSegmentation = EventSegmentation();
                overlappingWindowSegmentation = OverlappingWindowSegmentation();
                app.segmentationConfigurator.computers = {eventSegmentation,overlappingWindowSegmentation};
                app.segmentationConfigurator.reloadUI();
            end
            app.updateEventDetectionTablesVisibility();
            app.updateLabelingPanelVisibility();
        end

        % Button pushed function: classifyButton
        function classifyButtonPushed(app, event)
            if(app.checkClassificationConfiguration())

                app.confusionMatrixPlotter.clearPlot();
                
                classifier = app.classificationConfigurator.createComputerWithUIParameters();
                
                if (app.isHoldOutValidation())
                    [predictedLabels, truthLabels] = app.holdOutClassification(classifier);
                else
                    [predictedLabels, truthLabels] = app.leaveOneSubjectOutClassification(classifier);
                end
                
                labelIdxs = app.getIncludeLabelIdxs();
                
                labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
                
                if(any(predictedLabels == ClassesMap.kNullClass))
                    confusionMatrixData = confusionmat(truthLabels,predictedLabels,'Order',[labelIdxs 0]);
                    classNames = [labelGrouping.classNames(labelIdxs), Constants.kNullClassGroupStr];
                    containsNullClass = true;
                else
                    confusionMatrixData = confusionmat(truthLabels,predictedLabels,'Order',labelIdxs);
                    classNames = labelGrouping.classNames(labelIdxs);
                    containsNullClass = false;
                end
                
                app.confusionMatrix = ConfusionMatrix(confusionMatrixData);
                app.confusionMatrix.containsNullClass = containsNullClass;
                
                app.plotConfusionMatrix(app.confusionMatrix,classNames);
            end
        end

        % Button pushed function: regroupButton
        function regroupButtonPushed(app, event)

            if ~isempty(app.confusionMatrix)
                labelGrouping = app.groupingConfiguratorRegrouping.getCurrentLabelGrouping();
                if ~isempty(labelGrouping)
                    if app.groupedWithDefaultLabelGrouping
                        
                        app.confusionMatrixPlotter.clearPlot();
                        
                        regroupedConfusionMatrix = app.groupConfusionMatrix(labelGrouping);
                                                
                        if(regroupedConfusionMatrix.containsNullClass)
                            classNames = [labelGrouping.classNames, Constants.kNullClassGroupStr];
                        else
                            classNames = labelGrouping.classNames;
                        end
                        
                        app.plotConfusionMatrix(regroupedConfusionMatrix,classNames);
                        
                        app.regroupingTextBox.Value = "";
                    else
                        app.regroupingTextBox.Value = 'regrouping can be used only if segments were grouped with the default label grouping';
                    end
                end
            end
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            tableNames = app.exportTablesList.Value;
            [~,idxs] = ismember(tableNames,app.exportTablesList.Items);
            
            tableNames = app.generatedTableNames(idxs);
            table = app.getGeneratedTableWithNames(tableNames);
            
            app.exportTable(table,Constants.kFeaturesTableFileName);
            
            if app.exportNormalisationValuesCheckBox.Value == 1
                app.exportNormalisationValues();
            end
        end

        % Value changed function: selectAllFilesTrainingCheckBox
        function selectAllFilesTrainingCheckBoxValueChanged(app, event)
            value = app.selectAllFilesTrainingCheckBox.Value;
            if(value == true)
                app.selectAllFilesInList(app.trainList);
            end
        end

        % Value changed function: selectAllFilesTestingCheckBox
        function selectAllFilesTestingCheckBoxValueChanged(app, event)
            value = app.selectAllFilesTestingCheckBox.Value;
            
            if(value == true)
                app.selectAllFilesInList(app.testList);
            end
        end

        % Selection changed function: validationButtonGroup
        function validationButtonGroupSelectionChanged(app, event)
            selectedButton = app.validationButtonGroup.SelectedObject;
            if(selectedButton == app.holdoutRadio)
                app.moveFileRightButton.Visible = true;
                app.moveFileLeftButton.Visible = true;
                app.testList.Visible = true;
                app.selectAllFilesTestingCheckBox.Visible = true;
            else
                nTestItems = length(app.testList.Items);
                if nTestItems > 0
                    app.trainList.Items(end+1:end+nTestItems) = app.testList.Items;
                end
                app.moveFileRightButton.Visible = false;
                app.moveFileLeftButton.Visible = false;
                app.testList.Visible = false;
                app.selectAllFilesTestingCheckBox.Visible = false;
            end
        end

        % Callback function
        function selectAllFilesCheckBoxValueChanged(app, event)
            value = app.selectAllFilesCheckBox.Value;
            if value == true
                app.filesList.Value = app.filesList.Items;
            end
        end

        % Button pushed function: saveFeatures
        function saveFeaturesButtonPushed(app, event)
            
            featureExtractor = app.featureExtractionConfigurator.createFeatureExtractorWithUIParameters();
            app.dataLoader.SaveComputer(featureExtractor,'generatedFeatures');
        end

        % Button pushed function: createButton
        function createButtonPushed(app, event)
            
            if(app.checkCreateTablesConfiguration())
                if(~app.isManualMode || app.getIncludeEvents() || app.getIncludeRanges())
                    
                    app.buildChainFromUI();
                    
                    labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
                    if(labelGrouping == app.defaultLabelGrouping)
                        app.groupedWithDefaultLabelGrouping = true;
                    else
                        app.groupedWithDefaultLabelGrouping = false;
                    end
                    
                    [app.featureTables, metrics] = app.executeChain(app.inputFilesList);
                    app.generatedTableNames = app.inputFilesList.Value;
                    
                    app.updateValidationTablesLists();
                    app.updateExportFeatureTablesList();
                    app.updateMetricsTable(metrics);
                end
            end
        end

        % Value changed function: selectAllInputFilesList
        function selectAllInputFilesListValueChanged(app, event)
            value = app.selectAllInputFilesList.Value;
            if(value)
                app.inputFilesList.Value = app.inputFilesList.Items;
            else
                 app.inputFilesList.Value = [];
            end
        end

        % Button pushed function: findFeatures
        function findFeaturesButtonPushed(app, event)
            mergedTable = app.featureTables.mergedTables();
            nFeatures = app.nFeaturesEditField.Value;
            app.featureSelector.findBestFeaturesForTable(mergedTable,nFeatures);
            app.updateSelectedFeatures();
            
        end

        % Button pushed function: selectFeaturesButton
        function selectFeaturesButtonPushed(app, event)
            if ~isempty(app.featureTables)
                for i = 1 : app.featureTables.nTables
                    app.featureSelector.selectFeaturesForTable(app.featureTables.tables(i));
                end
                app.updateExportFeatureTablesList();
            end
        end

        % Button pushed function: clearCacheButton
        function clearCacheButtonPushed(app, event)
            Cache.ClearAllCacheFiles();
            clear Cache;
        end

        % Button pushed function: visualizeARCButton
        function visualizeARCButtonPushed(app, event)

            Helper.PlotComputerGraph(app.arcChain);
        end

        % Button pushed function: executeChainFile
        function executeChainFileButtonPushed(app, event)
            app.groupedWithDefaultLabelGrouping = false;
            
            if ~isempty(app.chainFilesList.Value)
                app.arcChain = app.dataLoader.LoadComputer(app.chainFilesList.Value);
                app.fileLoader = app.arcChain;%first element of the chain should be a file loader
                
                [app.featureTables, metrics] = app.executeChain(app.inputFilesList);
                app.generatedTableNames = app.inputFilesList.Value;
                
                app.updateValidationTablesLists();
                app.updateExportFeatureTablesList();
                app.updateMetricsTable(metrics);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EvaluationAppUIFigure = uifigure;
            app.EvaluationAppUIFigure.Name = 'Evaluation App';
            app.segmentationPanel = uipanel(app.EvaluationAppUIFigure);
            app.segmentationPanel.Title = 'Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true];
            app.segmentationList = uilistbox(app.segmentationPanel);
            app.segmentationList.Items = {};
            app.segmentationList.Value = {};
            app.eventDetectionPanel = uipanel(app.EvaluationAppUIFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectionPanel.Visible = 'off';
            app.eventDetectorVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectorVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectorVariablesTable.RowName = {};
            app.eventDetectorVariablesTable.ColumnEditable = [false true];
            app.eventDetectorList = uilistbox(app.eventDetectionPanel);
            app.eventDetectorList.Items = {};
            app.eventDetectorList.Value = {};
            app.eventDetectionLabel = uilabel(app.eventDetectionPanel);
            app.eventDetectionLabel.Text = '';
            app.preprocessingPanel = uipanel(app.EvaluationAppUIFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingPanel.Visible = 'off';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.segmentsSourceButtonGroup = uibuttongroup(app.EvaluationAppUIFigure);
            app.segmentsSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @segmentsSourceButtonGroupSelectionChanged, true);
            app.segmentsSourceButtonGroup.Title = 'Segments Source';
            app.manualSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.manualSegmentationRadio.Text = 'Manual annotations';
            app.manualSegmentationRadio.Value = true;
            app.automaticSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.automaticSegmentationRadio.Text = 'Automatic segmentation';
            app.validationPanel = uipanel(app.EvaluationAppUIFigure);
            app.validationPanel.Title = 'Validation';
            app.testList = uilistbox(app.validationPanel);
            app.testList.Items = {};
            app.testList.Multiselect = 'on';
            app.testList.Value = {};
            app.trainList = uilistbox(app.validationPanel);
            app.trainList.Items = {};
            app.trainList.Multiselect = 'on';
            app.trainList.Value = {};
            app.moveFileRightButton = uibutton(app.validationPanel, 'push');
            app.moveFileRightButton.ButtonPushedFcn = createCallbackFcn(app, @moveFileRightButtonPushed, true);
            app.moveFileRightButton.Text = '>>';
            app.moveFileLeftButton = uibutton(app.validationPanel, 'push');
            app.moveFileLeftButton.ButtonPushedFcn = createCallbackFcn(app, @moveFileLeftButtonPushed, true);
            app.moveFileLeftButton.Text = '<<';
            app.selectAllFilesTrainingCheckBox = uicheckbox(app.validationPanel);
            app.selectAllFilesTrainingCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesTrainingCheckBoxValueChanged, true);
            app.selectAllFilesTrainingCheckBox.Text = 'select all';
            app.selectAllFilesTestingCheckBox = uicheckbox(app.validationPanel);
            app.selectAllFilesTestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesTestingCheckBoxValueChanged, true);
            app.selectAllFilesTestingCheckBox.Text = 'select all';
            app.validationButtonGroup = uibuttongroup(app.validationPanel);
            app.validationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @validationButtonGroupSelectionChanged, true);
            app.holdoutRadio = uiradiobutton(app.validationButtonGroup);
            app.holdoutRadio.Text = 'Holdout';
            app.holdoutRadio.Value = true;
            app.crossValidationRadio = uiradiobutton(app.validationButtonGroup);
            app.crossValidationRadio.Text = 'Cross Validation';
            app.groupingPanel = uipanel(app.EvaluationAppUIFigure);
            app.groupingPanel.Title = 'Label Grouping';
            app.labelGroupingsList = uilistbox(app.groupingPanel);
            app.labelGroupingsList.Items = {};
            app.labelGroupingsList.Value = {};
            app.includeLabelsTable = uitable(app.groupingPanel);
            app.includeLabelsTable.ColumnName = {'Include class'; ''};
            app.includeLabelsTable.RowName = {};
            app.includeLabelsTable.ColumnEditable = [false true];
            app.featureExtractionPanel = uipanel(app.EvaluationAppUIFigure);
            app.featureExtractionPanel.Title = 'Feature Extraction';
            app.shouldNormalizeFeaturesCheckBox = uicheckbox(app.featureExtractionPanel);
            app.shouldNormalizeFeaturesCheckBox.Text = 'Normalize';
            app.shouldNormalizeFeaturesCheckBox.Value = true;
            app.featuresSourceButtonGroup = uibuttongroup(app.featureExtractionPanel);
            app.featuresFileRadio = uiradiobutton(app.featuresSourceButtonGroup);
            app.featuresFileRadio.Text = 'File';
            app.featuresFileRadio.Value = true;
            app.featuresManuallyRadio = uiradiobutton(app.featuresSourceButtonGroup);
            app.featuresManuallyRadio.Text = 'Manually';
            app.featureExtractionFileList = uilistbox(app.featureExtractionPanel);
            app.featureExtractionFileList.Items = {};
            app.featureExtractionFileList.Value = {};
            app.manualFeatureExtractionPanel = uipanel(app.featureExtractionPanel);
            app.manualFeatureExtractionPanel.Visible = 'off';
            app.featuresList = uilistbox(app.manualFeatureExtractionPanel);
            app.featuresList.Items = {};
            app.featuresList.Value = {};
            app.selectedFeaturesList = uilistbox(app.manualFeatureExtractionPanel);
            app.selectedFeaturesList.Items = {};
            app.selectedFeaturesList.Value = {};
            app.addFeatureButton = uibutton(app.manualFeatureExtractionPanel, 'push');
            app.addFeatureButton.Text = '+';
            app.removeFeatureButton = uibutton(app.manualFeatureExtractionPanel, 'push');
            app.removeFeatureButton.Text = '-';
            app.StartEditFieldLabel = uilabel(app.manualFeatureExtractionPanel);
            app.StartEditFieldLabel.Visible = 'off';
            app.StartEditFieldLabel.Text = 'Start';
            app.featureStartRangeEditText = uieditfield(app.manualFeatureExtractionPanel, 'numeric');
            app.featureStartRangeEditText.Visible = 'off';
            app.featureStartRangeEditText.Value = 1;
            app.EndEditFieldLabel = uilabel(app.manualFeatureExtractionPanel);
            app.EndEditFieldLabel.Visible = 'off';
            app.EndEditFieldLabel.Text = 'End';
            app.featureEndRangeEditText = uieditfield(app.manualFeatureExtractionPanel, 'numeric');
            app.featureEndRangeEditText.Visible = 'off';
            app.featureEndRangeEditText.Value = 350;
            app.featureFullSegmentCheckBox = uicheckbox(app.manualFeatureExtractionPanel);
            app.featureFullSegmentCheckBox.Text = 'Full Segment';
            app.featureFullSegmentCheckBox.Value = true;
            app.AxesLabel = uilabel(app.manualFeatureExtractionPanel);
            app.AxesLabel.Text = 'Axes';
            app.featureAxisEditText = uieditfield(app.manualFeatureExtractionPanel, 'text');
            app.saveFeatures = uibutton(app.featureExtractionPanel, 'push');
            app.saveFeatures.ButtonPushedFcn = createCallbackFcn(app, @saveFeaturesButtonPushed, true);
            app.saveFeatures.Text = 'Save';
            app.confusionMatrixAxes = uiaxes(app.EvaluationAppUIFigure);
            title(app.confusionMatrixAxes, 'Title')
            xlabel(app.confusionMatrixAxes, 'X')
            ylabel(app.confusionMatrixAxes, 'Y')
            app.confusionMatrixAxes.Visible = 'off';
            app.regroupingPanel = uipanel(app.EvaluationAppUIFigure);
            app.regroupingPanel.Title = 'Regrouping';
            app.regroupingLabelingStrategyList = uilistbox(app.regroupingPanel);
            app.regroupingLabelingStrategyList.Items = {};
            app.regroupingLabelingStrategyList.Value = {};
            app.regroupingTextBox = uitextarea(app.regroupingPanel);
            app.regroupingTextBox.Editable = 'off';
            app.regroupButton = uibutton(app.regroupingPanel, 'push');
            app.regroupButton.ButtonPushedFcn = createCallbackFcn(app, @regroupButtonPushed, true);
            app.regroupButton.Text = 'Regroup';
            app.exportPanel = uipanel(app.EvaluationAppUIFigure);
            app.exportPanel.Title = 'Export';
            app.exportNormalisationValuesCheckBox = uicheckbox(app.exportPanel);
            app.exportNormalisationValuesCheckBox.Text = 'Norms';
            app.exportFormatGroup = uibuttongroup(app.exportPanel);
            app.exportFormatGroup.Title = 'Format';
            app.exportMatlabFormatCheckBox = uiradiobutton(app.exportFormatGroup);
            app.exportMatlabFormatCheckBox.Text = '.mat';
            app.exportTextFormatCheckBox = uiradiobutton(app.exportFormatGroup);
            app.exportTextFormatCheckBox.Text = '.txt';
            app.exportTextFormatCheckBox.Value = true;
            app.exportLabelTypeGroup = uibuttongroup(app.exportPanel);
            app.exportLabelTypeGroup.Title = 'Table Labels';
            app.exportTextLabelsRadio = uiradiobutton(app.exportLabelTypeGroup);
            app.exportTextLabelsRadio.Text = 'text';
            app.exportTextLabelsRadio.Value = true;
            app.exportNumberLabelsRadio = uiradiobutton(app.exportLabelTypeGroup);
            app.exportNumberLabelsRadio.Text = 'number';
            app.exportButton = uibutton(app.exportPanel, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'Export';
            app.exportTablesList = uilistbox(app.exportPanel);
            app.exportTablesList.Items = {};
            app.exportTablesList.Multiselect = 'on';
            app.exportTablesList.Value = {};
            app.annotationsPanel = uibuttongroup(app.EvaluationAppUIFigure);
            app.annotationsPanel.Title = 'Annotations';
            app.includeEventsCheckBox = uicheckbox(app.annotationsPanel);
            app.includeEventsCheckBox.Text = 'Events';
            app.includeEventsCheckBox.Value = true;
            app.includeRangesCheckBox = uicheckbox(app.annotationsPanel);
            app.includeRangesCheckBox.Text = 'Ranges';
            app.includeLabelsPanel = uipanel(app.EvaluationAppUIFigure);
            app.includeLabelsPanel.Title = 'Classification';
            app.classifiersList = uilistbox(app.includeLabelsPanel);
            app.classifiersList.Items = {};
            app.classifiersList.Multiselect = 'on';
            app.classifiersList.Value = {};
            app.classifiersVariablesTable = uitable(app.includeLabelsPanel);
            app.classifiersVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.classifiersVariablesTable.RowName = {};
            app.classifiersVariablesTable.ColumnEditable = [false true];
            app.classifyButton = uibutton(app.includeLabelsPanel, 'push');
            app.classifyButton.ButtonPushedFcn = createCallbackFcn(app, @classifyButtonPushed, true);
            app.classifyButton.Text = 'Classify';
            app.featureSelectionPanel = uipanel(app.EvaluationAppUIFigure);
            app.featureSelectionPanel.Title = 'Feature Selection';
            app.findFeatures = uibutton(app.featureSelectionPanel, 'push');
            app.findFeatures.ButtonPushedFcn = createCallbackFcn(app, @findFeaturesButtonPushed, true);
            app.findFeatures.Text = 'Find';
            app.printFeatures = uibutton(app.featureSelectionPanel, 'push');
            app.printFeatures.Text = 'Print';
            app.selectFeaturesButton = uibutton(app.featureSelectionPanel, 'push');
            app.selectFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @selectFeaturesButtonPushed, true);
            app.selectFeaturesButton.Text = 'Select';
            app.NumberofFeaturesEditFieldLabel = uilabel(app.featureSelectionPanel);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features';
            app.nFeaturesEditField = uieditfield(app.featureSelectionPanel, 'numeric');
            app.nFeaturesEditField.Value = 20;
            app.featuresTextBox = uitextarea(app.featureSelectionPanel);
            app.createButton = uibutton(app.EvaluationAppUIFigure, 'push');
            app.createButton.ButtonPushedFcn = createCallbackFcn(app, @createButtonPushed, true);
            app.createButton.Text = 'Execute';
            app.filesPanel = uipanel(app.EvaluationAppUIFigure);
            app.filesPanel.Title = 'Input Files';
            app.inputFilesList = uilistbox(app.filesPanel);
            app.inputFilesList.Items = {};
            app.inputFilesList.Multiselect = 'on';
            app.inputFilesList.Value = {};
            app.selectAllInputFilesList = uicheckbox(app.filesPanel);
            app.selectAllInputFilesList.ValueChangedFcn = createCallbackFcn(app, @selectAllInputFilesListValueChanged, true);
            app.selectAllInputFilesList.Text = 'select all';
            app.clearCacheButton = uibutton(app.EvaluationAppUIFigure, 'push');
            app.clearCacheButton.ButtonPushedFcn = createCallbackFcn(app, @clearCacheButtonPushed, true);
            app.clearCacheButton.Text = 'Clear Cache';
            app.metricsPanel = uipanel(app.EvaluationAppUIFigure);
            app.metricsPanel.Title = 'Performance Assessment';
            app.metricsTable = uitable(app.metricsPanel);
            app.metricsTable.ColumnName = {'Algorithm'; 'Flops'; 'Memory'; 'Communication'};
            app.metricsTable.RowName = {};
            app.metricsTable.ColumnEditable = [true false false false];
            app.visualizeARCButton = uibutton(app.EvaluationAppUIFigure, 'push');
            app.visualizeARCButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeARCButtonPushed, true);
            app.visualizeARCButton.Text = 'Visualize ARC';
            app.labelingPanel = uipanel(app.EvaluationAppUIFigure);
            app.labelingPanel.Title = 'Labeling';
            app.labelingPanel.Visible = 'off';
            app.labelingStrategiesList = uilistbox(app.labelingPanel);
            app.labelingStrategiesList.Items = {};
            app.labelingStrategiesList.Value = {};
            app.labelingVariablesTable = uitable(app.labelingPanel);
            app.labelingVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.labelingVariablesTable.RowName = {};
            app.labelingVariablesTable.ColumnEditable = [false true];
            app.executeChainFile = uibutton(app.EvaluationAppUIFigure, 'push');
            app.executeChainFile.ButtonPushedFcn = createCallbackFcn(app, @executeChainFileButtonPushed, true);
            app.executeChainFile.Text = 'Execute from File';
            app.chainFilesList = uilistbox(app.EvaluationAppUIFigure);
            app.chainFilesList.Items = {};
            app.chainFilesList.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = EvaluationApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EvaluationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EvaluationAppUIFigure)
        end
    end
end