classdef OptimizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimizationAppUIFigure         matlab.ui.Figure
        optimizeButton                  matlab.ui.control.Button
        eventDetectionPanel             matlab.ui.container.Panel
        eventDetectionVariablesTable    matlab.ui.control.Table
        eventDetectionTable             matlab.ui.control.Table
        preprocessingPanel              matlab.ui.container.Panel
        preprocessingVariablesTable     matlab.ui.control.Table
        preprocessingTable              matlab.ui.control.Table
        signalsPanel                    matlab.ui.container.Panel
        signalsTable                    matlab.ui.control.Table
        metricsPanel                    matlab.ui.container.Panel
        AccuracySlider_2Label           matlab.ui.control.Label
        accuracySlider                  matlab.ui.control.Slider
        FlopsSlider_2Label              matlab.ui.control.Label
        flopsSlider                     matlab.ui.control.Slider
        CommunicationSlider_2Label      matlab.ui.control.Label
        communicationSlider             matlab.ui.control.Slider
        MemoryLabel                     matlab.ui.control.Label
        memorySlider                    matlab.ui.control.Slider
        constraintsPanel                matlab.ui.container.Panel
        constraintsTable                matlab.ui.control.Table
        segmentationPanel               matlab.ui.container.Panel
        segmentationVariablesTable      matlab.ui.control.Table
        segmentationTable               matlab.ui.control.Table
        featureExtractionPanel          matlab.ui.container.Panel
        featureExtractionVariablesTable  matlab.ui.control.Table
        featureExtractionTable          matlab.ui.control.Table
        classificationPanel             matlab.ui.container.Panel
        classificationVariablesTable    matlab.ui.control.Table
        classificationTable             matlab.ui.control.Table
        computeStatisticsButton         matlab.ui.control.Button
        statisticsPanel                 matlab.ui.container.Panel
        statisticsTable                 matlab.ui.control.Table
        validationPanel                 matlab.ui.container.Panel
        testList                        matlab.ui.control.ListBox
        trainList                       matlab.ui.control.ListBox
        moveFileRightButton             matlab.ui.control.Button
        moveFileLeftButton              matlab.ui.control.Button
        selectAllFilesTrainingCheckBox  matlab.ui.control.CheckBox
        selectAllFilesTestingCheckBox   matlab.ui.control.CheckBox
        kSignalNLabel                   matlab.ui.control.Label
        kSignalLabel_2                  matlab.ui.control.Label
        kEventLabel                     matlab.ui.control.Label
        kSegmentLabel                   matlab.ui.control.Label
        kTableLabel                     matlab.ui.control.Label
        kResultLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        fileNames;
        fileLoader;
        dataLoader;
        annotations;
        labelingStrategy;
        
        preprocessingConfigurator;
        eventDetectionConfigurator;
        segmentationConfigurator;
        featureExtractionConfigurator;
        classificationConfigurator;
    end
    
    methods (Access = private)
        
        function fillSignals(app)
            dataFiles = Helper.listDataFiles();
            if ~isempty(dataFiles)
                fileName = dataFiles{1};
                [~, columnNames] = app.dataLoader.loadData(fileName);
                
                nSignals = length(columnNames);
                app.signalsTable.Data = table(columnNames', true(nSignals,1));
            end
        end
        
        function fillFiles(app)
            app.trainList.Items = app.fileNames(1:end);
            app.trainList.Value = app.trainList.Items;
        end
        
        function selectedFilesStr = popSelectedFilesFromList(~,list)
            selectedFilesStr = list.Value;
            [~,selectedFileIdxs] = ismember(selectedFilesStr,list.Items);
            listIndices = setdiff(1:length(list.Items),selectedFileIdxs);
            list.Items = list.Items(listIndices);
        end
        
        function list = pushFilesToList(~,files,list)
            nItems = length(list.Items);
            nFiles = length(files);
            list.Items(nItems + 1:nItems + nFiles) = files;
        end
        
        function selectAllFilesInList(~,list)
            list.Value = list.Items;
        end
        
        function fileNames = getSelectedTrainFiles(app)
            fileNames = app.trainList.Value;
        end
    
        function fileNames = getSelectedTestFiles(app)
            fileNames = app.testList.Value;
        end
        
        function buildDetectionChainFromUI(app)
            app.fileLoader = FileLoader();
            
            propertyGetter = PropertyGetter('data');
            propertyGetter.outputPort = ComputerPort(ComputerPortType.kSignalN);

            preprocessor = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
            eventDetector = app.eventDetectorConfigurator.createComputerWithUIParameters();
            
            segmentsMapper = SegmentsClassToLabelMapper(app.labelingStrategy);
            
            app.arcChain = CompositeComputer();
            app.arcChain.addComputer(app.fileLoader);
            app.arcChain.addComputer(propertyGetter);
            app.arcChain.addComputer(preprocessor);
            app.arcChain.addComputer(eventDetector);
            app.arcChain.addComputer(EventsLabeler());
            app.arcChain.addComputer(segmentsMapper);
        end
        
        function eventsPerFile = executeDetectionChain(app, detectionChain, selectedFileIdxs)
            selectedFileNames = app.fileNames(selectedFileIdxs);
            nFiles = length(selectedFileIdxs);
            eventsPerFile = cell(1,nFiles);
            
            for i = 1 : nFiles
                
                app.fileLoader.fileName = selectedFileNames{i};
                
                selectedFileIdx = selectedFileIdxs(i);
                annotation = app.annotations(selectedFileIdx);
                
                Computer.SetSharedContextVariable(Constants.kSharedVariableCurrentAnnotationFile, annotation);
                
                eventsPerFile{i} = Computer.ExecuteChain(detectionChain,'start',true);
            end
        end
        
        function selectedIdxs = getSelectedIdxsForList(~, uiList)
            selectedStrs = uiList.Value;
            [~,selectedIdxs] = ismember(selectedStrs,uiList.Items);
        end
    
        function computerMap = createInputComputerMap(app)
            computers = Palette.AllComputers();
            types = cellfun(@(x) x.inputPort.type,computers,'UniformOutput',false);
            computerMap = app.createComputerMap(computers,types);
        end
        
        function computerMap = createOutputComputerMap(app)
            computers = Palette.AllComputers();
            types = cellfun(@(x) x.outputPort.type,computers,'UniformOutput',false);
            computerMap = app.createComputerMap(computers,types);
        end
    
        function computerMap = createComputerMap(~,computers,types)
            types = [types{:}]';
            nComputersPerType = sum((types == 0:ComputerPortType.kNull));
            
            computerMap = cell(ComputerPortType.kNull+1,1);
            for i = 1 : ComputerPortType.kNull+1
                computerMap{i} = cell(1,nComputersPerType(i));
            end
            
            countsPerType = ones(1,ComputerPortType.kNull);
            for i = 1 : length(computers)
                computer = computers{i};
                type = types(i)+1;
                count = countsPerType(type);
                cellArray = computerMap{type};
                cellArray{count} = computer;
                computerMap{type} = cellArray;
                countsPerType(type) = count + 1;
            end
        end
    
        function selectedSignals = getSelectedSignals(app)
            selectedSignals = app.signalsTable.Data{:,2};
        end
        
        function printComputerMap(~,computerMap)
            for i = 1 : ComputerPortType.kNull
                str = Constants.kComputerTypeStrings{i};
                fprintf('%s: ',str);
                computers = computerMap{i};
                for j = 1 : size(computers,2)
                    computer = computers{j};
                    fprintf('%s, ',computer.name);
                end
                fprintf('\n');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.fileNames = Helper.listDataFiles();
            
            app.fileLoader = FileLoader();
            app.dataLoader = DataLoader();
            app.annotations = app.dataLoader.loadAllAnnotations();
            app.labelingStrategy = app.dataLoader.loadAllLabelingStrategies();
            app.labelingStrategy = app.labelingStrategy(3); % take by defaule just the second labeling strategy
            
            preprocessingComputers = Palette.PreprocessingComputers();
            eventDetectionComputers = Palette.EventDetectionComputers();
            segmentationComputers = Palette.SegmentationComputers();
            featureComputers = FeatureExtractor.CreateDefaultFeatureExtractors();
            classificationComputers = Palette.ClassificationComputers();
            
            app.preprocessingConfigurator = ComputerTestFixConfigurator(preprocessingComputers,app.preprocessingTable,app.preprocessingVariablesTable);
            app.eventDetectionConfigurator = ComputerTestFixConfigurator(eventDetectionComputers,app.eventDetectionTable,app.eventDetectionVariablesTable);
            app.segmentationConfigurator = ComputerTestFixConfigurator(segmentationComputers,app.segmentationTable,app.segmentationVariablesTable);
            app.featureExtractionConfigurator = ComputerTestFixConfigurator(featureComputers,app.featureExtractionTable,app.featureExtractionVariablesTable);
            app.classificationConfigurator = ComputerTestFixConfigurator(classificationComputers,app.classificationTable,app.classificationVariablesTable);
            
            app.fillSignals();
            app.fillFiles();
        end

        % Button pushed function: computeStatisticsButton
        function computeStatisticsButtonPushed(app, event)
            
            stagesStrs = {'Signals','Preprocessing','Event Detection','Segmentation','Feature Extraction','Classification','Total'};
            nStages = length(stagesStrs);
            
            selectedSignals = app.getSelectedSignals();
            nSignals = sum(selectedSignals);
            
            statisticsCellArray = cell(nStages,2);
            statisticsCellArray(:,1) = stagesStrs;
            
            statisticsCellArray{1,2} = nSignals;
            
            testFixes = app.preprocessingConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{2,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            testFixes = app.eventDetectionConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{3,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            testFixes = app.segmentationConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{4,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            testFixes = app.featureExtractionConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{5,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            testFixes = app.classificationConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{6,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            total = 1;
            for i = 1 : nStages-1
                total = total * statisticsCellArray{i,2};
            end
            statisticsCellArray{nStages,2} = total;
            app.statisticsTable.Data = statisticsCellArray;
        end

        % Button pushed function: moveFileRightButton
        function moveFileRightButtonPushed(app, event)
            
            selectedFilesStr = app.popSelectedFilesFromList(app.trainList);
            app.pushFilesToList(selectedFilesStr,app.testList);
        end

        % Value changed function: selectAllFilesTrainingCheckBox
        function selectAllFilesTrainingCheckBoxValueChanged(app, event)
            value = app.selectAllFilesTrainingCheckBox.Value;
            if value
                app.selectAllFilesInList(app.trainList);
            end
        end

        % Value changed function: selectAllFilesTestingCheckBox
        function selectAllFilesTestingCheckBoxValueChanged(app, event)
            value = app.selectAllFilesTestingCheckBox.Value;
            if value
                app.selectAllFilesInList(app.testList);
            end
        end

        % Button pushed function: optimizeButton
        function optimizeButtonPushed(app, event)
            inputComputerMap = app.createInputComputerMap();
            outputComputerMap = app.createOutputComputerMap();
            fprintf('*input*\n');
            app.printComputerMap(inputComputerMap);
            fprintf('\n*output*\n');
            app.printComputerMap(outputComputerMap);
            
            [detectionResults, metrics] = app.executeDetectionChain(app.trainList,detectionChain);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimizationAppUIFigure = uifigure('Visible', 'off');
            app.OptimizationAppUIFigure.Name = 'Optimization App';
            app.optimizeButton = uibutton(app.OptimizationAppUIFigure, 'push');
            app.optimizeButton.ButtonPushedFcn = createCallbackFcn(app, @optimizeButtonPushed, true);
            app.optimizeButton.Text = 'Optimize';
            app.eventDetectionPanel = uipanel(app.OptimizationAppUIFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectionVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.eventDetectionVariablesTable.RowName = {};
            app.eventDetectionVariablesTable.ColumnEditable = [false true true true];
            app.eventDetectionTable = uitable(app.eventDetectionPanel);
            app.eventDetectionTable.ColumnName = {'Computer'; ''};
            app.eventDetectionTable.RowName = {};
            app.eventDetectionTable.ColumnEditable = [false true];
            app.preprocessingPanel = uipanel(app.OptimizationAppUIFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.preprocessingVariablesTable.RowName = {};
            app.preprocessingVariablesTable.ColumnEditable = [false true true true];
            app.preprocessingTable = uitable(app.preprocessingPanel);
            app.preprocessingTable.ColumnName = {'Computer'; ''};
            app.preprocessingTable.RowName = {};
            app.preprocessingTable.ColumnEditable = [false true];
            app.signalsPanel = uipanel(app.OptimizationAppUIFigure);
            app.signalsPanel.Title = 'Signals';
            app.signalsTable = uitable(app.signalsPanel);
            app.signalsTable.ColumnName = {'Signal'; ''};
            app.signalsTable.RowName = {};
            app.signalsTable.ColumnEditable = [false true];
            app.metricsPanel = uipanel(app.OptimizationAppUIFigure);
            app.metricsPanel.Title = 'Metrics';
            app.AccuracySlider_2Label = uilabel(app.metricsPanel);
            app.AccuracySlider_2Label.Text = 'Accuracy';
            app.accuracySlider = uislider(app.metricsPanel);
            app.FlopsSlider_2Label = uilabel(app.metricsPanel);
            app.FlopsSlider_2Label.Text = 'Flops';
            app.flopsSlider = uislider(app.metricsPanel);
            app.CommunicationSlider_2Label = uilabel(app.metricsPanel);
            app.CommunicationSlider_2Label.Text = 'Communication';
            app.communicationSlider = uislider(app.metricsPanel);
            app.MemoryLabel = uilabel(app.metricsPanel);
            app.MemoryLabel.Text = 'Memory';
            app.memorySlider = uislider(app.metricsPanel);
            app.constraintsPanel = uipanel(app.OptimizationAppUIFigure);
            app.constraintsPanel.Title = 'Constraints';
            app.constraintsTable = uitable(app.constraintsPanel);
            app.constraintsTable.ColumnName = {'Metric'; 'Min'; 'Max'};
            app.constraintsTable.RowName = {};
            app.constraintsTable.ColumnEditable = [false true true];
            app.segmentationPanel = uipanel(app.OptimizationAppUIFigure);
            app.segmentationPanel.Title = 'Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true true true];
            app.segmentationTable = uitable(app.segmentationPanel);
            app.segmentationTable.ColumnName = {'Computer'; ''};
            app.segmentationTable.RowName = {};
            app.segmentationTable.ColumnEditable = [false true];
            app.featureExtractionPanel = uipanel(app.OptimizationAppUIFigure);
            app.featureExtractionPanel.Title = 'Feature Extraction';
            app.featureExtractionVariablesTable = uitable(app.featureExtractionPanel);
            app.featureExtractionVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.featureExtractionVariablesTable.RowName = {};
            app.featureExtractionVariablesTable.ColumnEditable = [false true true true];
            app.featureExtractionTable = uitable(app.featureExtractionPanel);
            app.featureExtractionTable.ColumnName = {'Computer'; ''};
            app.featureExtractionTable.RowName = {};
            app.featureExtractionTable.ColumnEditable = [false true];
            app.classificationPanel = uipanel(app.OptimizationAppUIFigure);
            app.classificationPanel.Title = 'Classification';
            app.classificationVariablesTable = uitable(app.classificationPanel);
            app.classificationVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.classificationVariablesTable.RowName = {};
            app.classificationVariablesTable.ColumnEditable = [false true true true];
            app.classificationTable = uitable(app.classificationPanel);
            app.classificationTable.ColumnName = {'Computer'; ''};
            app.classificationTable.RowName = {};
            app.classificationTable.ColumnEditable = [false true];
            app.computeStatisticsButton = uibutton(app.OptimizationAppUIFigure, 'push');
            app.computeStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @computeStatisticsButtonPushed, true);
            app.computeStatisticsButton.Text = 'Compute Statistics';
            app.statisticsPanel = uipanel(app.OptimizationAppUIFigure);
            app.statisticsPanel.Title = 'Statistics per Stage';
            app.statisticsTable = uitable(app.statisticsPanel);
            app.statisticsTable.ColumnName = {'Stage'; 'N'};
            app.statisticsTable.RowName = {};
            app.statisticsTable.ColumnEditable = false;
            app.validationPanel = uipanel(app.OptimizationAppUIFigure);
            app.validationPanel.Title = 'Validation';
            app.testList = uilistbox(app.validationPanel);
            app.testList.Items = {};
            app.testList.Multiselect = 'on';
            app.testList.Value = {};
            app.trainList = uilistbox(app.validationPanel);
            app.trainList.Items = {};
            app.trainList.Multiselect = 'on';
            app.trainList.Value = {};
            app.moveFileRightButton = uibutton(app.validationPanel, 'push');
            app.moveFileRightButton.ButtonPushedFcn = createCallbackFcn(app, @moveFileRightButtonPushed, true);
            app.moveFileRightButton.Text = '>>';
            app.moveFileLeftButton = uibutton(app.validationPanel, 'push');
            app.moveFileLeftButton.Text = '<<';
            app.selectAllFilesTrainingCheckBox = uicheckbox(app.validationPanel);
            app.selectAllFilesTrainingCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesTrainingCheckBoxValueChanged, true);
            app.selectAllFilesTrainingCheckBox.Text = 'select all';
            app.selectAllFilesTestingCheckBox = uicheckbox(app.validationPanel);
            app.selectAllFilesTestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesTestingCheckBoxValueChanged, true);
            app.selectAllFilesTestingCheckBox.Text = 'select all';
            app.kSignalNLabel = uilabel(app.OptimizationAppUIFigure);
            app.kSignalNLabel.Text = 'kSignalN';
            app.kSignalLabel_2 = uilabel(app.OptimizationAppUIFigure);
            app.kSignalLabel_2.Text = 'kSignal';
            app.kEventLabel = uilabel(app.OptimizationAppUIFigure);
            app.kEventLabel.Text = 'kEvent';
            app.kSegmentLabel = uilabel(app.OptimizationAppUIFigure);
            app.kSegmentLabel.Text = 'kSegment';
            app.kTableLabel = uilabel(app.OptimizationAppUIFigure);
            app.kTableLabel.Text = 'kTable';
            app.kResultLabel = uilabel(app.OptimizationAppUIFigure);
            app.kResultLabel.Text = 'kResult';
            % Show the figure after all components are created
            app.OptimizationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptimizationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimizationAppUIFigure)
        end
    end
end