classdef FeatureSelectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        featureSelectionPanel           matlab.ui.container.Panel
        featuresTextBox                 matlab.ui.control.EditField
        findFeatures                    matlab.ui.control.Button
        printFeatures                   matlab.ui.control.Button
        shouldNormalizeFeaturesCheckBox  matlab.ui.control.CheckBox
        selectFeaturesButton            matlab.ui.control.Button
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        nFeaturesEditField              matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: selectFeaturesButton
        function selectFeaturesButtonPushed(app, event)
                  if ~isempty(app.trainTable)
                maxFeatureIdx = max(app.featureSelector.selectedFeatureIdxs);
                if (maxFeatureIdx < app.trainTable.width && maxFeatureIdx < app.testTable.width)
                    app.featureSelector.selectFeaturesForTable(app.trainTable);
                    
                    if ~isempty(app.testTable)
                        app.featureSelector.selectFeaturesForTable(app.testTable);
                    end
                end
            end
            
            %group labels
            app.updateTrainTableLabel();
            app.updateTestTableLabel();
        end

        % Button pushed function: findFeatures
        function findFeaturesButtonPushed(app, event)
                   
            if ~isempty(app.trainTable)
                nFeatures = app.getNFeatures();
                app.featureSelector.findBestFeaturesForTable(app.trainTable,nFeatures);
                app.updateBestFeaturesText();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.featureSelectionPanel = uipanel(app.UIFigure);
            app.featureSelectionPanel.Title = 'Feature Extraction';
            app.featuresTextBox = uieditfield(app.featureSelectionPanel, 'text');
            app.findFeatures = uibutton(app.featureSelectionPanel, 'push');
            app.findFeatures.ButtonPushedFcn = createCallbackFcn(app, @findFeaturesButtonPushed, true);
            app.findFeatures.Text = 'Find';
            app.printFeatures = uibutton(app.featureSelectionPanel, 'push');
            app.printFeatures.Text = 'Print';
            app.shouldNormalizeFeaturesCheckBox = uicheckbox(app.featureSelectionPanel);
            app.shouldNormalizeFeaturesCheckBox.Text = 'Normalize';
            app.shouldNormalizeFeaturesCheckBox.Value = true;
            app.selectFeaturesButton = uibutton(app.featureSelectionPanel, 'push');
            app.selectFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @selectFeaturesButtonPushed, true);
            app.selectFeaturesButton.Text = 'Select';
            app.NumberofFeaturesEditFieldLabel = uilabel(app.featureSelectionPanel);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features';
            app.nFeaturesEditField = uieditfield(app.featureSelectionPanel, 'numeric');
            app.nFeaturesEditField.Value = 20;
        end
    end

    methods (Access = public)

        % Construct app
        function app = FeatureSelectionApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end