classdef EvaluationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        segmentationPanel               matlab.ui.container.Panel
        segmentationVariablesTable      matlab.ui.control.Table
        segmentationList                matlab.ui.control.ListBox
        createButton                    matlab.ui.control.Button
        trainTableLabel                 matlab.ui.control.Label
        testTableLabel                  matlab.ui.control.Label
        eventDetectionPanel             matlab.ui.container.Panel
        eventDetectorVariablesTable     matlab.ui.control.Table
        eventDetectorList               matlab.ui.control.ListBox
        eventDetectionLabel             matlab.ui.control.Label
        preprocessingPanel              matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList        matlab.ui.control.ListBox
        segmentsSourceButtonGroup       matlab.ui.container.ButtonGroup
        manualSegmentationRadio         matlab.ui.control.RadioButton
        automaticSegmentationRadio      matlab.ui.control.RadioButton
        dataPanel                       matlab.ui.container.Panel
        testList                        matlab.ui.control.ListBox
        trainList                       matlab.ui.control.ListBox
        moveFileRightButton             matlab.ui.control.Button
        moveFileLeftButton              matlab.ui.control.Button
        selectAllFilesTrainingCheckBox  matlab.ui.control.CheckBox
        selectAllFilesTestingCheckBox   matlab.ui.control.CheckBox
        groupingPanel                   matlab.ui.container.Panel
        labelingStrategiesList          matlab.ui.control.ListBox
        groupedTestTableLabel           matlab.ui.control.Label
        groupedTrainTableLabel          matlab.ui.control.Label
        labelButton                     matlab.ui.control.Button
        featureSelectionPanel           matlab.ui.container.Panel
        featuresTextBox                 matlab.ui.control.EditField
        findFeatures                    matlab.ui.control.Button
        printFeatures                   matlab.ui.control.Button
        shouldNormalizeFeaturesCheckBox  matlab.ui.control.CheckBox
        selectFeaturesButton            matlab.ui.control.Button
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        nFeaturesEditField              matlab.ui.control.NumericEditField
        confusionMatrixAxes             matlab.ui.control.UIAxes
        regroupingPanel                 matlab.ui.container.Panel
        regroupingLabelingStrategyList  matlab.ui.control.ListBox
        regroupingTextBox               matlab.ui.control.TextArea
        regroupButton                   matlab.ui.control.Button
        exportPanel                     matlab.ui.container.Panel
        exportTrainDataCheckBox         matlab.ui.control.CheckBox
        exportTestDataCheckBox          matlab.ui.control.CheckBox
        exportNormalisationValuesCheckBox  matlab.ui.control.CheckBox
        exportFormatGroup               matlab.ui.container.ButtonGroup
        exportMatlabFormatCheckBox      matlab.ui.control.RadioButton
        exportTextFormatCheckBox        matlab.ui.control.RadioButton
        exportLabelTypeGroup            matlab.ui.container.ButtonGroup
        exportTextLabelsRadio           matlab.ui.control.RadioButton
        exportNumberLabelsRadio         matlab.ui.control.RadioButton
        exportButton                    matlab.ui.control.Button
        annotationsPanel                matlab.ui.container.ButtonGroup
        includeEventsCheckBox           matlab.ui.control.CheckBox
        includeRangesCheckBox           matlab.ui.control.CheckBox
        groupingPanel_2                 matlab.ui.container.Panel
        includeLabelsList               matlab.ui.control.ListBox
        classifyButton                  matlab.ui.control.Button
        validationButtonGroup           matlab.ui.container.ButtonGroup
        holdoutRadio                    matlab.ui.control.RadioButton
        crossValidationRadio            matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        
        %input data
        fileNames;
        filesMap;
        annotations;
        
        %tables
        trainTable;
        testTable;
        
        %data
        dataLoader;
        groupedTrainTable;
        groupedTestTable;
        
        %arc
        arcChain;
        
        %configurators
        preprocessingConfigurator;
        eventDetectorConfigurator;
        segmentationConfigurator;
        labelingConfigurator;
        labelingConfiguratorRegrouping;
        
        %helpers
        featureSelector;
        featureNormalizer;
        trainer;
        tableExporter;
        confusionMatrixPlotter;
        manualSegmentation;
        defaultLabelingStrategy;
        
        %state
        groupedWithDefaultLabelingStrategy;
        isManualMode;
        
        %results
        confusionMatrix;
    end
    
    methods (Access = private)
        
        function loadConfigurators(app)
            
            signalComputers = Computer.PreprocessingComputers();
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                signalComputers,...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            app.preprocessingConfigurator.setDefaultColumnNames();
            
            eventDetectors = Computer.EventDetectionComputers();
            
            app.eventDetectorConfigurator = EventDetectorConfigurator(...
                eventDetectors,...
            app.eventDetectorList...
                ,app.eventDetectorVariablesTable);
            
            
            segmentationStrategies = {app.manualSegmentation};
            
            app.segmentationConfigurator = SegmentationConfigurator(...
                segmentationStrategies,...
                app.segmentationList...
                ,app.segmentationVariablesTable);
            
            labelingStrategies = app.dataLoader.loadAllLabelingStrategies();
            app.defaultLabelingStrategy = labelingStrategies{1};
            
            app.labelingConfigurator = LabelingConfigurator(...
                labelingStrategies,app.labelingStrategiesList);
            
            labelingStrategiesRegrouping = labelingStrategies(2:end);
            
            app.labelingConfiguratorRegrouping = LabelingConfigurator(...
                labelingStrategiesRegrouping, app.regroupingLabelingStrategyList);
            
            app.updateEventDetectionTablesVisibility();            
        end
        
        function loadFilesMap(app)
            for i = 1 : length(app.fileNames)
                fileName = app.fileNames{i};
                app.filesMap(fileName) = i;
            end
        end
        
        function populateFilesList(app)
            app.trainList.Items = app.fileNames(1:end-1);
            app.testList.Items = app.fileNames(end);
        end
        
        function selectedFilesStr = popSelectedFilesFromList(~,list)
            selectedFilesStr = list.Value;
            [~,selectedFileIdxs] = ismember(selectedFilesStr,list.Items);
            listIndices = setdiff(1:length(list.Items),selectedFileIdxs);
            list.Items = list.Items(listIndices);
        end
        
        function list = pushFilesToList(~,files,list)
            nItems = length(list.Items);
            nFiles = length(files);
            list.Items(nItems + 1:nItems + nFiles) = files;
        end
        
        function table = createTableWithFilesInList(app,list)
            selectedFileStr = list.Value;
            [~,selectedFileIdxs] = ismember(selectedFileStr,list.Items);
            
            table = [];
            if ~isempty(selectedFileIdxs)
                fileIdxs = app.getGlobalFileIdxs(list,selectedFileIdxs);
                table = app.tableSet.mergedTableForIndices(fileIdxs);
            end
        end
        
        function fileIdxs = getGlobalFileIdxs(app,list,inFileIdxs)
            nFileIdxs = length(inFileIdxs);
            fileIdxs = zeros(1,nFileIdxs);
            for i = 1 : nFileIdxs
                fileIdx = inFileIdxs(i);
                fileName = list.Items{fileIdx};
                fileIdx = app.filesMap(fileName);
                fileIdxs(i) = fileIdx;
            end
        end
        
        function printTable(app,table)
            if ~isempty(table)
                printer = StatisticsPrinter();
                printer.printTableStatistics(table, app.defaultLabelingStrategy, 0);
            end
        end
        
        function printGroupedTable(app,table)
            if ~isempty(table)
                printer = StatisticsPrinter();
                labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
                printer.printTableStatistics(table, labelingStrategy, 0);
            end
        end
        
        function normalizeGroupedTables(app)
            if app.shouldNormalizeFeaturesCheckBox.Value == 1
                
                if ~isempty(app.groupedTrainTable)
                    validFeatureIdxs = FeatureNormalizer.ComputeValidFeaturesForTable(app.groupedTrainTable);
                    
                    app.groupedTrainTable.filterTableToColumns([validFeatureIdxs true]);
                    app.groupedTestTable.filterTableToColumns([validFeatureIdxs true]);
                    
                    app.featureNormalizer.fit(app.groupedTrainTable);
                    app.groupedTrainTable = app.featureNormalizer.normalize(app.groupedTrainTable);
                end
                
                if ~isempty(app.groupedTestTable.table)
                    app.groupedTestTable = app.featureNormalizer.normalize(app.groupedTestTable);
                end
            end
        end
        
        function groupedConfusionMatrix = groupConfusionMatrix(app,labelingStrategy)
            numClasses = labelingStrategy.numClasses;
            groupedConfusionMatrixData = zeros(numClasses,numClasses);
            confusionMatrixData = app.confusionMatrix.data;
            for i = 1 : app.confusionMatrix.nClasses
                row = labelingStrategy.labelForClass(i);
                for j = 1 : app.confusionMatrix.nClasses
                    col = labelingStrategy.labelForClass(j);
                    groupedConfusionMatrixData(row,col) = groupedConfusionMatrixData(row,col) + confusionMatrixData(i,j);
                end
            end
            groupedConfusionMatrix = ConfusionMatrix(groupedConfusionMatrixData);
        end
        
        function plotConfusionMatrix(app,confusionMatrix,classNames)
            app.confusionMatrixAxes.Visible = true;
            app.confusionMatrixPlotter.plotConfusionMatrix(confusionMatrix,classNames);
        end
            
        function exportTable(app,table,fileName)
            if ~isempty(table.table)
                
                previousLabels = table.label;
                
                if app.exportTextLabelsRadio.Value == 1
                    labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
                    table.label = labelingStrategy.labelsToString(table.label)';
                else
                    table.label = table.label - 1;
                end
                
                if app.exportMatlabFormatCheckBox.Value == 1
                    app.dataLoader.saveData(table.table,fileName);
                else
                    app.tableExporter.exportTable(table.table,fileName);
                end
                
                table.label = previousLabels;
            end
        end
        
        function exportNormalisationValues(app)
            selectedFeatureIdxs = app.featureSelector.selectedFeatureIdxs;
            if ~isempty(app.featureNormalizer.means)
                means = app.featureNormalizer.means(selectedFeatureIdxs);
                stds = app.featureNormalizer.stds(selectedFeatureIdxs);
                normalisationValues = array2table([means' stds']);
                
                normalisationValues.Properties.VariableNames = {'means','deviations'};
                normalisationValuesArray = table2array(normalisationValues);
                transposedNormalisationValues = array2table(normalisationValuesArray.');
                transposedNormalisationValues.Properties.RowNames = normalisationValues.Properties.VariableNames;
                
                featureNames = app.featureExtractor.getFeatureNames();
                transposedNormalisationValues.Properties.VariableNames = featureNames(selectedFeatureIdxs);
                
                app.tableExporter.exportTable(transposedNormalisationValues,Constants.kNormalisationFileName);
            end
        end
        
        %% UI
        function selectedIdxs = getSelectedIdxsForList(~, uiList)
            selectedStrs = uiList.Value;
            [~,selectedIdxs] = ismember(selectedStrs,uiList.Items);
        end
    
        function updateEventDetectionTablesVisibility(app)
            if app.isManualMode
                app.annotationsPanel.Visible = 'On';
                app.eventDetectionPanel.Visible = 'Off';
                app.preprocessingPanel.Visible = 'Off';
                app.preprocessingSignalComputerList.Visible = 'Off';
            else
                app.annotationsPanel.Visible = 'Off';
                app.eventDetectionPanel.Visible = 'On';
                app.preprocessingPanel.Visible = 'On';
                app.preprocessingSignalComputerList.Visible = 'On';
            end
        end
        
        function updateTrainTableLabel(app)
            if isempty(app.trainTable)
                app.trainTableLabel.Text = sprintf('train table:\n empty');
            else
                tableHeight = app.trainTable.height;
                tableWidth = app.trainTable.width;
                app.trainTableLabel.Text = sprintf('train table:\n %dx%d',tableHeight,tableWidth);
            end
        end
        
        function updateTestTableLabel(app)
            if isempty(app.testTable)
                app.testTableLabel.Text = sprintf('test table:\n empty');
            else
                tableHeight = app.testTable.height;
                tableWidth = app.testTable.width;
                app.testTableLabel.Text = sprintf('test table:\n %dx%d',tableHeight,tableWidth);
            end
        end
        
        function idx = getSelectedSegmentLoaderIdx(app)
            switch app.segmentationGroup.Selectedappect
                case app.manualSegmentationRadio
                    idx = 1;
                case app.simplifiedSegmentationRadio
                    idx = 2;
            end
        end
        
        function updateGroupedTablesLabels(app)
            labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
            app.groupedTrainTableLabel.Text = app.labelForTable(app.groupedTrainTable,'train',labelingStrategy);
            app.groupedTestTableLabel.Text = app.labelForTable(app.groupedTestTable,'test',labelingStrategy);
        end
        
        function idx = getSelectedRegroupingLabelingIdx(app)
            idx = -1;
            switch app.regroupGroup.Selectedappect
                case app.regroupingGoodBadLabelingRadio
                    idx = 2;
                case app.regroupingGroupedLabelingRadio
                    idx = 3;
            end
        end
        
        function nFeatures = getNFeatures(app)
            nFeatures = app.nFeaturesEditField.Value;
        end
        
        function updateBestFeaturesText(app)
            app.featuresTextBox.Value = Helper.arrayToString(app.featureSelector.selectedFeatureIdxs,', ');
        end
        
        function includeEvents = getIncludeEvents(app)
            includeEvents = app.includeEventsCheckBox.Value;
        end
        
        function includeRanges = getIncludeRanges(app)
            includeRanges = app.includeRangesCheckBox.Value;
        end
        
        function idxs = getIncludeLabelIdxs(app)
            idxStr = app.includeLabelsList.Value;
            [~,idxs] = ismember(idxStr,app.includeLabelsList.Items);
        end
        
        function table = groupTable(~,table,labelingStrategy)
            if ~isempty(table.table)
                table.label = labelingStrategy.labelsForClasses(table.label);
            end
        end
        
        function labelStr = labelForTable(~,table,tableTypeStr,labelingStrategy)
            
            if isempty(table)
                labelStr = sprintf('%s table:\n empty',tableTypeStr);
            else
                labelStr = sprintf('%s table:\n %dx%d\n (%s)',tableTypeStr,table.height,...
                    table.width,labelingStrategy.name);
            end
        end

        function loadIncludeLabelsList(app,labelingStrategy)
            app.includeLabelsList.Items = labelingStrategy.classNames;
            app.includeLabelsList.Value = app.includeLabelsList.Items;
        end
        
        function selectAllFilesInList(~,list)
            list.Value = list.Items;
        end
        
        function createChainFromUI(app)
            
            segmentationAlgorithm = app.segmentationConfigurator.createSegmentationStrategyWithUIParameters();

            if app.isManualMode
                
                segmentationAlgorithm.includeEvents = app.getIncludeEvents();
                segmentationAlgorithm.includeRanges = app.getIncludeRanges();
                app.arcChain = segmentationAlgorithm;
                lastElement = segmentationAlgorithm;
                %segmentationAlgorithm.nextComputers = mapper;
            else
                preprocessor = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
                eventDetector = app.eventDetectorConfigurator.createEventDetectorWithUIParameters();
                labeler = SegmentsLabeler();
                
                app.arcChain = Sensor(1:18);
                app.arcChain.nextComputers = preprocessor;
                preprocessor.nextComputers = eventDetector;
                eventDetector.nextComputers = segmentationAlgorithm;
                segmentationAlgorithm.nextComputers = labeler;
                lastElement = labeler;
                %labeler.nextComputers = mapper;
            end
            
            kDefaultNumSignals = 7;
            kDefaultSegmentSize = 451;
            kMiddlePartStart = 200;
            kMiddlePartEnd = 350;
            
            segmentRanges = [FeatureRange(1,kMiddlePartStart-1),...
                FeatureRange(kMiddlePartStart,kMiddlePartEnd),...
                FeatureRange(kMiddlePartEnd+1,kDefaultSegmentSize)];
            
            featureExtractor = FeatureExtractor.CreateDefaultFeatureExtractors(kDefaultNumSignals,segmentRanges);
            lastElement.nextComputers = featureExtractor;
        end
    
        function table = executeChain(app,uiList)
            selectedFileIdxs = app.getSelectedIdxsForList(uiList);
            selectedFileNames = uiList.Items(selectedFileIdxs);
            files = app.dataLoader.loadDataFiles(selectedFileNames);
            
            globalFileIdxs = app.getGlobalFileIdxs(uiList,selectedFileIdxs);
            
            nFiles = length(files);
            
            sharedVariables = containers.Map();
            Computer.sharedContext(sharedVariables);
            tables = repmat(Table,1,nFiles);
            
            for i = 1 : nFiles
                selectedFileIdx = globalFileIdxs(i);
                
                sharedVariables(Constants.kSharedVariableCurrentDataFile) = files(i);
                
                sharedVariables(Constants.kSharedVariableCurrentAnnotationFile) = ...
                    app.annotations(selectedFileIdx);
                
                tables(i) = Computer.computeChain(app.arcChain,files(i));
            end
            
            tableSet = TableSet(tables);
            table = tableSet.mergedTables();
        end
        
        
        function b = isHoldOutValidation(app)
            b = app.holdoutRadio.Value;
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear ClassesMap;
            
            app.isManualMode = true;
            app.dataLoader = DataLoader();
            app.annotations = app.dataLoader.loadAllAnnotations();
            app.manualSegmentation = ManualSegmentation(app.annotations);
            app.fileNames = Helper.listDataFiles();
         
            app.featureNormalizer = FeatureNormalizer();
            app.featureSelector = FeatureSelector();
            app.trainer = Trainer();
            
            app.tableExporter = TableExporter();
            app.filesMap = containers.Map();
            
            app.confusionMatrixPlotter = ConfusionMatrixPlotter(app.confusionMatrixAxes);
                        
            app.loadFilesMap();
            
            app.loadConfigurators();
            
            app.populateFilesList();
            app.selectAllFilesInList(app.trainList);
            app.selectAllFilesInList(app.testList);
            
            app.updateBestFeaturesText();
        end

        % Button pushed function: moveFileRightButton
        function moveFileRightButtonPushed(app, event)
            
            selectedFilesStr = app.popSelectedFilesFromList(app.trainList);
            app.pushFilesToList(selectedFilesStr,app.testList);
        end

        % Button pushed function: moveFileLeftButton
        function moveFileLeftButtonPushed(app, event)
            
            selectedFileStr = app.popSelectedFilesFromList(app.testList);
            app.pushFilesToList(selectedFileStr,app.trainList);
        end

        % Selection changed function: segmentsSourceButtonGroup
        function segmentsSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.segmentsSourceButtonGroup.SelectedObject;
            if(selectedButton == app.manualSegmentationRadio)
                
                app.isManualMode = true;
                app.updateEventDetectionTablesVisibility();
                
                app.segmentationConfigurator.segmentationStrategies = {app.manualSegmentation};
                app.segmentationConfigurator.reloadUI();
            else
                app.isManualMode = false;
                app.updateEventDetectionTablesVisibility();
                
                segmentationStrategy = EventSegmentation();
                app.segmentationConfigurator.segmentationStrategies = {segmentationStrategy};
                app.segmentationConfigurator.reloadUI();
            end
        end

        % Button pushed function: createButton
        function createButtonPushed(app, event)
            
            if(~app.isManualMode || app.getIncludeEvents() || app.getIncludeRanges())
                
                app.createChainFromUI();
                app.trainTable = app.executeChain(app.trainList);
                app.testTable = app.executeChain(app.testList);
                
                app.updateTrainTableLabel();
                app.updateTestTableLabel();
            end
        end

        % Button pushed function: findFeatures
        function findFeaturesButtonPushed(app, event)
            
            if ~isempty(app.groupedTrainTable)
                nFeatures = app.getNFeatures();
                app.featureSelector.findBestFeaturesForTable(app.groupedTrainTable,nFeatures);
                app.updateBestFeaturesText();
            end
        end

        % Button pushed function: selectFeaturesButton
        function selectFeaturesButtonPushed(app, event)
            
            if ~isempty(app.groupedTrainTable)
                maxFeatureIdx = max(app.featureSelector.selectedFeatureIdxs);
                if (maxFeatureIdx < app.groupedTrainTable.width && maxFeatureIdx < app.groupedTestTable.width)
                    app.featureSelector.selectFeaturesForTable(app.groupedTrainTable);
                    
                    if ~isempty(app.groupedTestTable)
                        app.featureSelector.selectFeaturesForTable(app.groupedTestTable);
                    end
                end
            end
            
            %group labels
            app.updateGroupedTablesLabels();
        end

        % Button pushed function: printFeatures
        function printFeaturesButtonPushed(app, event)
            
            if ~isempty(app.groupedTrainTable)
                featureIdxs = app.featureSelector.selectedFeatureIdxs;
                for i = 1 : length(featureIdxs)
                    featureIdx = featureIdxs(i);
                    featureName = app.trainTable.columnNames{featureIdx};
                    fprintf('%d - %s\n',featureIdx,featureName);
                end
            end
        end

        % Button pushed function: classifyButton
        function classifyButtonPushed(app, event)
            
            if ~isempty(app.groupedTrainTable) && ~isempty(app.groupedTestTable)
                
                includeLabelIdxs = app.getIncludeLabelIdxs();
                
                if ~isempty(includeLabelIdxs)
                    
                    app.confusionMatrixPlotter.clearPlot();
                    
                    app.groupedTrainTable.filterTableToLabels(includeLabelIdxs);
                    app.groupedTestTable.filterTableToLabels(includeLabelIdxs);
                    
                    app.trainer.train(app.groupedTrainTable);
 
                    predictedLabels = app.trainer.test(app.groupedTestTable);
                    
                    confusionMatrixData = confusionmat(app.groupedTestTable.label,predictedLabels,'Order',includeLabelIdxs);
                    app.confusionMatrix = ConfusionMatrix(confusionMatrixData);
                    
                    labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
                    classNames = labelingStrategy.classNames(includeLabelIdxs);
                    app.plotConfusionMatrix(app.confusionMatrix,classNames);
                end
            end
        end

        % Button pushed function: regroupButton
        function regroupButtonPushed(app, event)
            
            if ~isempty(app.confusionMatrix)
                labelingStrategy = app.labelingConfiguratorRegrouping.getCurrentLabelingStrategy();
                if ~isempty(labelingStrategy)
                    if app.groupedWithDefaultLabelingStrategy
                        
                        app.confusionMatrixPlotter.clearPlot();
                        
                        regroupedConfusionMatrix = app.groupConfusionMatrix(labelingStrategy);
                        
                        app.plotConfusionMatrix(regroupedConfusionMatrix,labelingStrategy.classNames);
                        
                        app.regroupingTextBox.Value = "";
                    else
                        app.regroupingTextBox.Value = 'regrouping can be used only if segments were grouped with the default labeling strategy';
                    end
                end
            end
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            
            if app.exportTrainDataCheckBox.Value == 1
                table = app.groupedTrainTable;
                if isempty(table)
                    table = app.trainTable;
                end
                
                app.exportTable(table,Constants.kTrainDataFileName);
            end
            
            if app.exportTestDataCheckBox.Value == 1 
                table = app.groupedTestTable;
                if isempty(table)
                    table = app.testTable;
                end
                app.exportTable(table,Constants.kTestDataFileName);
            end
            
            if app.exportNormalisationValuesCheckBox.Value == 1
                app.exportNormalisationValues();
            end
        end

        % Value changed function: selectAllFilesTrainingCheckBox
        function selectAllFilesTrainingCheckBoxValueChanged(app, event)
            value = app.selectAllFilesTrainingCheckBox.Value;
            if(value == true)
                app.selectAllFilesInList(app.trainList);
            end
        end

        % Value changed function: selectAllFilesTestingCheckBox
        function selectAllFilesTestingCheckBoxValueChanged(app, event)
            value = app.selectAllFilesTestingCheckBox.Value;
            
            if(value == true)
                app.selectAllFilesInList(app.testList);
            end
        end

        % Button pushed function: labelButton
        function groupButtonPushed(app, event)
            
            labelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
            if(labelingStrategy == app.defaultLabelingStrategy)
                app.groupedWithDefaultLabelingStrategy = true;
            else
                app.groupedWithDefaultLabelingStrategy = false;
            end
            
            app.loadIncludeLabelsList(labelingStrategy);
            
            app.groupedTrainTable = app.groupTable(app.trainTable,labelingStrategy);
            app.groupedTestTable = app.groupTable(app.testTable,labelingStrategy);
            
            app.normalizeGroupedTables();
            app.updateGroupedTablesLabels();
        end

        % Selection changed function: validationButtonGroup
        function validationButtonGroupSelectionChanged(app, event)
            selectedButton = app.validationButtonGroup.SelectedObject;
            if(selectedButton == app.holdoutRadio)
                app.moveFileRightButton.Visible = true;
                app.moveFileLeftButton.Visible = true;
                app.testList.Visible = true;
                app.selectAllFilesTestingCheckBox.Visible = true;
            else
                
                app.moveFileRightButton.Visible = false;
                app.moveFileLeftButton.Visible = false;
                app.testList.Visible = false;
                app.selectAllFilesTestingCheckBox.Visible = false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.segmentationPanel = uipanel(app.UIFigure);
            app.segmentationPanel.Title = 'Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true];
            app.segmentationList = uilistbox(app.segmentationPanel);
            app.segmentationList.Items = {};
            app.segmentationList.Value = {};
            app.createButton = uibutton(app.segmentationPanel, 'push');
            app.createButton.ButtonPushedFcn = createCallbackFcn(app, @createButtonPushed, true);
            app.createButton.Text = 'Create';
            app.trainTableLabel = uilabel(app.segmentationPanel);
            app.trainTableLabel.Text = '';
            app.testTableLabel = uilabel(app.segmentationPanel);
            app.testTableLabel.Text = '';
            app.eventDetectionPanel = uipanel(app.UIFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectionPanel.Visible = 'off';
            app.eventDetectorVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectorVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectorVariablesTable.RowName = {};
            app.eventDetectorVariablesTable.ColumnEditable = [false true];
            app.eventDetectorList = uilistbox(app.eventDetectionPanel);
            app.eventDetectorList.Items = {};
            app.eventDetectorList.Value = {};
            app.eventDetectionLabel = uilabel(app.eventDetectionPanel);
            app.eventDetectionLabel.Text = '';
            app.preprocessingPanel = uipanel(app.UIFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingPanel.Visible = 'off';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.segmentsSourceButtonGroup = uibuttongroup(app.UIFigure);
            app.segmentsSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @segmentsSourceButtonGroupSelectionChanged, true);
            app.segmentsSourceButtonGroup.Title = 'Segments Source';
            app.manualSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.manualSegmentationRadio.Text = 'Manual annotations';
            app.manualSegmentationRadio.Value = true;
            app.automaticSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.automaticSegmentationRadio.Text = 'Automatic segmentation';
            app.dataPanel = uipanel(app.UIFigure);
            app.dataPanel.Title = 'Data';
            app.testList = uilistbox(app.dataPanel);
            app.testList.Items = {};
            app.testList.Multiselect = 'on';
            app.testList.Value = {};
            app.trainList = uilistbox(app.dataPanel);
            app.trainList.Items = {};
            app.trainList.Multiselect = 'on';
            app.trainList.Value = {};
            app.moveFileRightButton = uibutton(app.dataPanel, 'push');
            app.moveFileRightButton.ButtonPushedFcn = createCallbackFcn(app, @moveFileRightButtonPushed, true);
            app.moveFileRightButton.Text = '>>';
            app.moveFileLeftButton = uibutton(app.dataPanel, 'push');
            app.moveFileLeftButton.ButtonPushedFcn = createCallbackFcn(app, @moveFileLeftButtonPushed, true);
            app.moveFileLeftButton.Text = '<<';
            app.selectAllFilesTrainingCheckBox = uicheckbox(app.dataPanel);
            app.selectAllFilesTrainingCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesTrainingCheckBoxValueChanged, true);
            app.selectAllFilesTrainingCheckBox.Text = 'select all';
            app.selectAllFilesTestingCheckBox = uicheckbox(app.dataPanel);
            app.selectAllFilesTestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesTestingCheckBoxValueChanged, true);
            app.selectAllFilesTestingCheckBox.Text = 'select all';
            app.groupingPanel = uipanel(app.UIFigure);
            app.groupingPanel.Title = 'Grouping';
            app.labelingStrategiesList = uilistbox(app.groupingPanel);
            app.labelingStrategiesList.Items = {};
            app.labelingStrategiesList.Value = {};
            app.groupedTestTableLabel = uilabel(app.groupingPanel);
            app.groupedTestTableLabel.Text = '';
            app.groupedTrainTableLabel = uilabel(app.groupingPanel);
            app.groupedTrainTableLabel.Text = '';
            app.labelButton = uibutton(app.groupingPanel, 'push');
            app.labelButton.ButtonPushedFcn = createCallbackFcn(app, @groupButtonPushed, true);
            app.labelButton.Text = 'Group';
            app.featureSelectionPanel = uipanel(app.UIFigure);
            app.featureSelectionPanel.Title = 'Feature Extraction';
            app.featuresTextBox = uieditfield(app.featureSelectionPanel, 'text');
            app.findFeatures = uibutton(app.featureSelectionPanel, 'push');
            app.findFeatures.ButtonPushedFcn = createCallbackFcn(app, @findFeaturesButtonPushed, true);
            app.findFeatures.Text = 'Find';
            app.printFeatures = uibutton(app.featureSelectionPanel, 'push');
            app.printFeatures.ButtonPushedFcn = createCallbackFcn(app, @printFeaturesButtonPushed, true);
            app.printFeatures.Text = 'Print';
            app.shouldNormalizeFeaturesCheckBox = uicheckbox(app.featureSelectionPanel);
            app.shouldNormalizeFeaturesCheckBox.Text = 'Normalize';
            app.shouldNormalizeFeaturesCheckBox.Value = true;
            app.selectFeaturesButton = uibutton(app.featureSelectionPanel, 'push');
            app.selectFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @selectFeaturesButtonPushed, true);
            app.selectFeaturesButton.Text = 'Select';
            app.NumberofFeaturesEditFieldLabel = uilabel(app.featureSelectionPanel);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features';
            app.nFeaturesEditField = uieditfield(app.featureSelectionPanel, 'numeric');
            app.nFeaturesEditField.Value = 20;
            app.confusionMatrixAxes = uiaxes(app.UIFigure);
            title(app.confusionMatrixAxes, 'Title')
            xlabel(app.confusionMatrixAxes, 'X')
            ylabel(app.confusionMatrixAxes, 'Y')
            app.confusionMatrixAxes.Visible = 'off';
            app.regroupingPanel = uipanel(app.UIFigure);
            app.regroupingPanel.Title = 'Regrouping';
            app.regroupingLabelingStrategyList = uilistbox(app.regroupingPanel);
            app.regroupingLabelingStrategyList.Items = {};
            app.regroupingLabelingStrategyList.Value = {};
            app.regroupingTextBox = uitextarea(app.regroupingPanel);
            app.regroupingTextBox.Editable = 'off';
            app.regroupButton = uibutton(app.regroupingPanel, 'push');
            app.regroupButton.ButtonPushedFcn = createCallbackFcn(app, @regroupButtonPushed, true);
            app.regroupButton.Text = 'Regroup';
            app.exportPanel = uipanel(app.UIFigure);
            app.exportPanel.Title = 'Export';
            app.exportTrainDataCheckBox = uicheckbox(app.exportPanel);
            app.exportTrainDataCheckBox.Text = 'Train Data';
            app.exportTrainDataCheckBox.Value = true;
            app.exportTestDataCheckBox = uicheckbox(app.exportPanel);
            app.exportTestDataCheckBox.Text = 'Test Data';
            app.exportTestDataCheckBox.Value = true;
            app.exportNormalisationValuesCheckBox = uicheckbox(app.exportPanel);
            app.exportNormalisationValuesCheckBox.Text = 'Norms';
            app.exportFormatGroup = uibuttongroup(app.exportPanel);
            app.exportFormatGroup.Title = 'Format';
            app.exportMatlabFormatCheckBox = uiradiobutton(app.exportFormatGroup);
            app.exportMatlabFormatCheckBox.Text = '.mat';
            app.exportTextFormatCheckBox = uiradiobutton(app.exportFormatGroup);
            app.exportTextFormatCheckBox.Text = '.txt';
            app.exportTextFormatCheckBox.Value = true;
            app.exportLabelTypeGroup = uibuttongroup(app.exportPanel);
            app.exportLabelTypeGroup.Title = 'Table Labels';
            app.exportTextLabelsRadio = uiradiobutton(app.exportLabelTypeGroup);
            app.exportTextLabelsRadio.Text = 'text';
            app.exportTextLabelsRadio.Value = true;
            app.exportNumberLabelsRadio = uiradiobutton(app.exportLabelTypeGroup);
            app.exportNumberLabelsRadio.Text = 'number';
            app.exportButton = uibutton(app.exportPanel, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'Export';
            app.annotationsPanel = uibuttongroup(app.UIFigure);
            app.annotationsPanel.Title = 'Annotations';
            app.includeEventsCheckBox = uicheckbox(app.annotationsPanel);
            app.includeEventsCheckBox.Text = 'Events';
            app.includeEventsCheckBox.Value = true;
            app.includeRangesCheckBox = uicheckbox(app.annotationsPanel);
            app.includeRangesCheckBox.Text = 'Ranges';
            app.groupingPanel_2 = uipanel(app.UIFigure);
            app.groupingPanel_2.Title = 'Classify';
            app.includeLabelsList = uilistbox(app.groupingPanel_2);
            app.includeLabelsList.Items = {};
            app.includeLabelsList.Multiselect = 'on';
            app.includeLabelsList.Value = {};
            app.classifyButton = uibutton(app.groupingPanel_2, 'push');
            app.classifyButton.ButtonPushedFcn = createCallbackFcn(app, @classifyButtonPushed, true);
            app.classifyButton.Text = 'Classify';
            app.validationButtonGroup = uibuttongroup(app.UIFigure);
            app.validationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @validationButtonGroupSelectionChanged, true);
            app.validationButtonGroup.Title = 'Validation';
            app.holdoutRadio = uiradiobutton(app.validationButtonGroup);
            app.holdoutRadio.Text = 'Holdout';
            app.holdoutRadio.Value = true;
            app.crossValidationRadio = uiradiobutton(app.validationButtonGroup);
            app.crossValidationRadio.Text = 'Cross Validation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EvaluationApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end