classdef OptimizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        createButton                    matlab.ui.control.Button
        eventDetectionPanel             matlab.ui.container.Panel
        eventDetectionVariablesTable    matlab.ui.control.Table
        eventDetectionTable             matlab.ui.control.Table
        preprocessingPanel              matlab.ui.container.Panel
        preprocessingVariablesTable     matlab.ui.control.Table
        preprocessingTable              matlab.ui.control.Table
        signalsPanel                    matlab.ui.container.Panel
        signalsTable                    matlab.ui.control.Table
        metricsPanel                    matlab.ui.container.Panel
        AccuracySlider_2Label           matlab.ui.control.Label
        accuracySlider                  matlab.ui.control.Slider
        FlopsSlider_2Label              matlab.ui.control.Label
        flopsSlider                     matlab.ui.control.Slider
        CommunicationSlider_2Label      matlab.ui.control.Label
        communicationSlider             matlab.ui.control.Slider
        constraintsPanel                matlab.ui.container.Panel
        constraintsTable                matlab.ui.control.Table
        segmentationPanel               matlab.ui.container.Panel
        segmentationVariablesTable      matlab.ui.control.Table
        segmentationTable               matlab.ui.control.Table
        featureExtractionPanel          matlab.ui.container.Panel
        featureExtractionVariablesTable  matlab.ui.control.Table
        featureExtractionTable          matlab.ui.control.Table
        classificationPanel             matlab.ui.container.Panel
        classificationVariablesTable    matlab.ui.control.Table
        classificationTable             matlab.ui.control.Table
        printButton                     matlab.ui.control.Button
        statisticsPanel                 matlab.ui.container.Panel
        statisticsTable                 matlab.ui.control.Table
        validationPanel                 matlab.ui.container.Panel
        testList                        matlab.ui.control.ListBox
        trainList                       matlab.ui.control.ListBox
        moveFileRightButton             matlab.ui.control.Button
        moveFileLeftButton              matlab.ui.control.Button
        selectAllFilesTrainingCheckBox  matlab.ui.control.CheckBox
        selectAllFilesTestingCheckBox   matlab.ui.control.CheckBox
        kSignalLabel                    matlab.ui.control.Label
        kSignalLabel_2                  matlab.ui.control.Label
        kEventLabel                     matlab.ui.control.Label
        kSegmentLabel                   matlab.ui.control.Label
        kTableLabel                     matlab.ui.control.Label
        kResultLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        fileNames;
        
        preprocessingConfigurator;
        eventDetectionConfigurator;
        segmentationConfigurator;
        featureExtractionConfigurator;
        classificationConfigurator;
    end
    
    methods (Access = private)
        
        function fillSignals(app)
            dataLoader = DataLoader();
            dataFiles = Helper.listDataFiles();
            if ~isempty(dataFiles)
                fileName = dataFiles{1};
                [~, columnNames] = dataLoader.loadData(fileName);
                
                nSignals = length(columnNames);
                app.signalsTable.Data = table(columnNames', true(nSignals,1));
            end
        end
        
        function fillFiles(app)
            
            app.trainList.Items = app.fileNames(1:end);
            app.trainList.Value = app.trainList.Items;
        end
        
        function selectedFilesStr = popSelectedFilesFromList(~,list)
            selectedFilesStr = list.Value;
            [~,selectedFileIdxs] = ismember(selectedFilesStr,list.Items);
            listIndices = setdiff(1:length(list.Items),selectedFileIdxs);
            list.Items = list.Items(listIndices);
        end
        
        function list = pushFilesToList(~,files,list)
            nItems = length(list.Items);
            nFiles = length(files);
            list.Items(nItems + 1:nItems + nFiles) = files;
        end
        
        function selectAllFilesInList(~,list)
            list.Value = list.Items;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.fileNames = Helper.listDataFiles();
            
            preprocessingComputers = Palette.PreprocessingComputers();
            eventDetectionComputers = Palette.EventDetectionComputers();
            segmentationComputers = Palette.SegmentationComputers();
            featureComputers = FeatureExtractor.CreateDefaultFeatureExtractors();
            classificationComputers = Palette.ClassificationComputers();
            
            app.preprocessingConfigurator = ComputerTestFixConfigurator(preprocessingComputers,app.preprocessingTable,app.preprocessingVariablesTable);
            app.eventDetectionConfigurator = ComputerTestFixConfigurator(eventDetectionComputers,app.eventDetectionTable,app.eventDetectionVariablesTable);
            app.segmentationConfigurator = ComputerTestFixConfigurator(segmentationComputers,app.segmentationTable,app.segmentationVariablesTable);
            app.featureExtractionConfigurator = ComputerTestFixConfigurator(featureComputers,app.featureExtractionTable,app.featureExtractionVariablesTable);
            app.classificationConfigurator = ComputerTestFixConfigurator(classificationComputers,app.classificationTable,app.classificationVariablesTable);
            
            app.fillSignals();
            app.fillFiles();
        end

        % Button pushed function: printButton
        function printButtonPushed(app, event)
            
            stagesStrs = {'Signals','Preprocessing','Event Detection','Segmentation','Feature Extraction','Total'};
            nStages = length(stagesStrs);
            
            selectedSignals = app.signalsTable.Data{:,2};
            nSignals = sum(selectedSignals);
            nSignals = 2^nSignals;
            
            statisticsCellArray = cell(nStages,2);
            statisticsCellArray(:,1) = stagesStrs;
            
            statisticsCellArray{1,2} = nSignals;
            
            testFixes = app.preprocessingConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{2,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            testFixes = app.eventDetectionConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{3,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            testFixes = app.segmentationConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{4,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            testFixes = app.featureExtractionConfigurator.createComputerTestFixWithUIParameters();
            statisticsCellArray{5,2} = ComputerTestFix.CountNCombinations(testFixes);
            
            total = 1;
            for i = 1 : nStages-1
                total = total * statisticsCellArray{i,2};
            end
            statisticsCellArray{nStages,2} = total;
            app.statisticsTable.Data = statisticsCellArray;
        end

        % Button pushed function: moveFileRightButton
        function moveFileRightButtonPushed(app, event)
            
            selectedFilesStr = app.popSelectedFilesFromList(app.trainList);
            app.pushFilesToList(selectedFilesStr,app.testList);
        end

        % Value changed function: selectAllFilesTrainingCheckBox
        function selectAllFilesTrainingCheckBoxValueChanged(app, event)
            value = app.selectAllFilesTrainingCheckBox.Value;
            app.selectAllFilesInList(app.trainList);
        end

        % Value changed function: selectAllFilesTestingCheckBox
        function selectAllFilesTestingCheckBoxValueChanged(app, event)
            value = app.selectAllFilesTestingCheckBox.Value;
            app.selectAllFilesInList(app.testList);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.createButton = uibutton(app.UIFigure, 'push');
            app.createButton.Text = 'Optimize';
            app.eventDetectionPanel = uipanel(app.UIFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectionVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.eventDetectionVariablesTable.RowName = {};
            app.eventDetectionVariablesTable.ColumnEditable = [false true true true];
            app.eventDetectionTable = uitable(app.eventDetectionPanel);
            app.eventDetectionTable.ColumnName = {'Computer'; ''};
            app.eventDetectionTable.RowName = {};
            app.eventDetectionTable.ColumnEditable = [false true];
            app.preprocessingPanel = uipanel(app.UIFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.preprocessingVariablesTable.RowName = {};
            app.preprocessingVariablesTable.ColumnEditable = [false true true true];
            app.preprocessingTable = uitable(app.preprocessingPanel);
            app.preprocessingTable.ColumnName = {'Computer'; ''};
            app.preprocessingTable.RowName = {};
            app.preprocessingTable.ColumnEditable = [false true];
            app.signalsPanel = uipanel(app.UIFigure);
            app.signalsPanel.Title = 'Signals';
            app.signalsTable = uitable(app.signalsPanel);
            app.signalsTable.ColumnName = {'Signal'; ''};
            app.signalsTable.RowName = {};
            app.signalsTable.ColumnEditable = [false true];
            app.metricsPanel = uipanel(app.UIFigure);
            app.metricsPanel.Title = 'Metrics';
            app.AccuracySlider_2Label = uilabel(app.metricsPanel);
            app.AccuracySlider_2Label.Text = 'Accuracy';
            app.accuracySlider = uislider(app.metricsPanel);
            app.FlopsSlider_2Label = uilabel(app.metricsPanel);
            app.FlopsSlider_2Label.Text = 'Flops';
            app.flopsSlider = uislider(app.metricsPanel);
            app.CommunicationSlider_2Label = uilabel(app.metricsPanel);
            app.CommunicationSlider_2Label.Text = 'Communication';
            app.communicationSlider = uislider(app.metricsPanel);
            app.constraintsPanel = uipanel(app.UIFigure);
            app.constraintsPanel.Title = 'Constraints';
            app.constraintsTable = uitable(app.constraintsPanel);
            app.constraintsTable.ColumnName = {'Metric'; 'Min'; 'Max'};
            app.constraintsTable.RowName = {};
            app.constraintsTable.ColumnEditable = [false true true];
            app.segmentationPanel = uipanel(app.UIFigure);
            app.segmentationPanel.Title = 'Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true true true];
            app.segmentationTable = uitable(app.segmentationPanel);
            app.segmentationTable.ColumnName = {'Computer'; ''};
            app.segmentationTable.RowName = {};
            app.segmentationTable.ColumnEditable = [false true];
            app.featureExtractionPanel = uipanel(app.UIFigure);
            app.featureExtractionPanel.Title = 'Feature Extraction';
            app.featureExtractionVariablesTable = uitable(app.featureExtractionPanel);
            app.featureExtractionVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.featureExtractionVariablesTable.RowName = {};
            app.featureExtractionVariablesTable.ColumnEditable = [false true true true];
            app.featureExtractionTable = uitable(app.featureExtractionPanel);
            app.featureExtractionTable.ColumnName = {'Computer'; ''};
            app.featureExtractionTable.RowName = {};
            app.featureExtractionTable.ColumnEditable = [false true];
            app.classificationPanel = uipanel(app.UIFigure);
            app.classificationPanel.Title = 'Classification';
            app.classificationVariablesTable = uitable(app.classificationPanel);
            app.classificationVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.classificationVariablesTable.RowName = {};
            app.classificationVariablesTable.ColumnEditable = [false true true true];
            app.classificationTable = uitable(app.classificationPanel);
            app.classificationTable.ColumnName = {'Computer'; ''};
            app.classificationTable.RowName = {};
            app.classificationTable.ColumnEditable = [false true];
            app.printButton = uibutton(app.UIFigure, 'push');
            app.printButton.ButtonPushedFcn = createCallbackFcn(app, @printButtonPushed, true);
            app.printButton.Text = 'Compute Statistics';
            app.statisticsPanel = uipanel(app.UIFigure);
            app.statisticsPanel.Title = 'Statistics per Stage';
            app.statisticsTable = uitable(app.statisticsPanel);
            app.statisticsTable.ColumnName = {'Stage'; 'N'};
            app.statisticsTable.RowName = {};
            app.statisticsTable.ColumnEditable = false;
            app.validationPanel = uipanel(app.UIFigure);
            app.validationPanel.Title = 'Validation';
            app.testList = uilistbox(app.validationPanel);
            app.testList.Items = {};
            app.testList.Multiselect = 'on';
            app.testList.Value = {};
            app.trainList = uilistbox(app.validationPanel);
            app.trainList.Items = {};
            app.trainList.Multiselect = 'on';
            app.trainList.Value = {};
            app.moveFileRightButton = uibutton(app.validationPanel, 'push');
            app.moveFileRightButton.ButtonPushedFcn = createCallbackFcn(app, @moveFileRightButtonPushed, true);
            app.moveFileRightButton.Text = '>>';
            app.moveFileLeftButton = uibutton(app.validationPanel, 'push');
            app.moveFileLeftButton.Text = '<<';
            app.selectAllFilesTrainingCheckBox = uicheckbox(app.validationPanel);
            app.selectAllFilesTrainingCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesTrainingCheckBoxValueChanged, true);
            app.selectAllFilesTrainingCheckBox.Text = 'select all';
            app.selectAllFilesTestingCheckBox = uicheckbox(app.validationPanel);
            app.selectAllFilesTestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesTestingCheckBoxValueChanged, true);
            app.selectAllFilesTestingCheckBox.Text = 'select all';
            app.kSignalLabel = uilabel(app.UIFigure);
            app.kSignalLabel.Text = 'kSignal';
            app.kSignalLabel_2 = uilabel(app.UIFigure);
            app.kSignalLabel_2.Text = 'kSignal';
            app.kEventLabel = uilabel(app.UIFigure);
            app.kEventLabel.Text = 'kEvent';
            app.kSegmentLabel = uilabel(app.UIFigure);
            app.kSegmentLabel.Text = 'kSegment';
            app.kTableLabel = uilabel(app.UIFigure);
            app.kTableLabel.Text = 'kTable';
            app.kResultLabel = uilabel(app.UIFigure);
            app.kResultLabel.Text = 'kResult';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptimizationApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end